classdef mesoscope_RCS_with_preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        frameDrop                       matlab.ui.control.Label
        queSize                         matlab.ui.control.Label
        MesoscopeControlLabel           matlab.ui.control.Label
        RecordingPanel                  matlab.ui.container.Panel
        PreviewButton                   matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.Button
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        CameraSettingsPanel             matlab.ui.container.Panel
        GainKnob                        matlab.ui.control.Knob
        GainKnobLabel                   matlab.ui.control.Label
        BufferSizeframesEditField       matlab.ui.control.NumericEditField
        BufferSizeframesEditFieldLabel  matlab.ui.control.Label
        BinSizepixelDropDown            matlab.ui.control.DropDown
        BinSizepixelDropDownLabel       matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
        FrameRatechEditField            matlab.ui.control.NumericEditField
        FrameRatechEditFieldLabel       matlab.ui.control.Label
        excitation3                     matlab.ui.control.DropDown
        LED3Label                       matlab.ui.control.Label
        excitation2                     matlab.ui.control.DropDown
        LED2Label                       matlab.ui.control.Label
        excitation1                     matlab.ui.control.DropDown
        LED1DropDownLabel               matlab.ui.control.Label
        Preview                         matlab.ui.control.UIAxes
        UIAxes3Graph                    matlab.ui.control.UIAxes
        UIAxes2Graph                    matlab.ui.control.UIAxes
        UIAxes1Graph                    matlab.ui.control.UIAxes
        LED3                            matlab.ui.control.UIAxes
        LED2                            matlab.ui.control.UIAxes
        LED1                            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        frameCounter        % Frame counter
        isRecording = true; % Flag to control the loop
        tlCamera; % camera object
        isPreview; %flag to control Preview functionality
        actualFrameNumber; %define number of frames
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
        ThorFolder = 'C:\Thorlabs\Scientific_Camera_Interfaces_Windows-2.1\Scientific Camera Interfaces\MATLAB';
        addpath(genpath(ThorFolder));
        cd(ThorFolder);
        NET.addAssembly([ThorFolder, '\Thorlabs.TSI.TLCamera.dll']);
        tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;
        serialNumbers = tlCameraSDK.DiscoverAvailableCameras;

        if serialNumbers.Count > 0
            disp('Opening the first camera');
            app.tlCamera = tlCameraSDK.OpenCamera(serialNumbers.Item(0), false);
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.ExposureTime_us = app.ExposureTimemsEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.Arm;
            disp('Camera initialized.');
        else
            error('No camera detected.');
        end
            catch ME
                disp(['Error during startup: ', ME.message]);
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

    % Stop preview if running
    if app.PreviewButton.Value
        app.PreviewButton.Value = false;
        PreviewButtonPushed(app, event); % Stop preview
    end

    % Perform Recording Logic
    disp('Starting recording...');
    app.isRecording = true;
    app.StopButton.Enable = 'on';
    app.RecordButton.Enable = 'off';
             if isempty(gcp('nocreate')) % Make a pool of workers
                parpool('local');
            end
            app.CameraSettingsPanel.Enable = "on";

            frameHandle(1)=app.LED1;
            frameHandle(2)=app.LED2;
            frameHandle(3)=app.LED3;

            graphHandle(1)=app.UIAxes1Graph;
            graphHandle(2)=app.UIAxes2Graph;
            graphHandle(3)=app.UIAxes3Graph;

            % Define custom colormaps
            bfpColormap = [linspace(0, 0, 255)', linspace(0, 0, 255)', linspace(0, 1, 255)'];  % Custom blue
            gfpColormap = [linspace(0, 0, 255)', linspace(0, 1, 255)', linspace(0, 0, 255)'];  % Custom green
            rfpColormap = [linspace(0, 1, 255)', linspace(0, 0, 255)', linspace(0, 0, 255)'];  % Custom red

            colorMAP{1}=bfpColormap;
            colorMAP{2}=gfpColormap;
            colorMAP{3}=rfpColormap;

            valueHistory=100;

            for i = 1:3
                framePixelValues{i} = nan(1, valueHistory);  % Pre-allocate with NaNs
            end

            capturedFrames = zeros(1,3);


            folderName{1}='405';
            folderName{2}='470';
            folderName{3}='565';

            baseDir = ['E:\imaging\' app.ExperimenterEditField.Value]; % Change this to your desired path
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            Subject_ID = app.SubjectEditField.Value;
            baseFolder = fullfile(baseDir, [Subject_ID '_' timestamp]);

            mkdir([baseFolder filesep folderName{1}]);
            mkdir([baseFolder filesep folderName{2}]);
            mkdir([baseFolder filesep folderName{3}]);


            % Buffer size limit (last 100 frames)
            valueHistory = 100;
            BinSize = str2num(app.BinSizepixelDropDown.Value);

            % see if camera is already loaded
                    if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
                         app.tlCamera.IssueSoftwareTrigger;
                         app.isRecording = true;
                     else
                         disp('camera already loaded...');
                         app.tlCamera.Disarm;
                         app.tlCamera.Dispose;
                    end

            % Set the FIFO frame buffer size. Default size is 1.
            app.tlCamera.MaximumNumberOfFramesToQueue = app.BufferSizeframesEditField.Value;
            app.tlCamera.ROIAndBin.BinX = BinSize;
            app.tlCamera.ROIAndBin.BinY = BinSize;

            % Start continuous image acquisition
            disp('Starting continuous image acquisition.');
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;

            frameCount = 0;

            fileID = fopen([baseFolder filesep Subject_ID  '_' timestamp '_config.txt'], 'w'); % Open file for writing
            fprintf(fileID, 'Start time: %s\n', timestamp);
            fprintf(fileID, 'Experimenter: %s\n', app.ExperimenterEditField.Value);
            fprintf(fileID, 'Subject ID: %s\n', app.SubjectEditField.Value);
            fprintf(fileID, 'Bin Size: %d\n', BinSize);

            app.StopButton.Enable = "on";

            %% Capture One Frame
            % Capture an image from the camera
            while app.isRecording
                % Check if image buffer has been filled
                NumberOfQueuedFrames = app.tlCamera.NumberOfQueuedFrames;
                app.queSize.Text= ['Frames remaining in the buffer: ', num2str(NumberOfQueuedFrames)];

                if (NumberOfQueuedFrames > 0)
                    imageFrame  = app.tlCamera.GetPendingFrameOrNull;
                    actualFrameNumber = imageFrame.FrameNumber;
                    app.frameDrop.Text= ['Dropped Frames: ', num2str(imageFrame.FrameNumber-frameCount)];

                    if mod(actualFrameNumber, 3) == 0
                        frameColor=1;
                    elseif mod(actualFrameNumber, 3) == 1
                        frameColor=2;
                    else
                        frameColor=3;
                    end

                    if ~isempty(imageFrame)
                        frameCount = frameCount + 1;
                        capturedFrames(frameColor) = capturedFrames(frameColor)+1;
                        % Get the image data as 1D uint16 array
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        disp(['Min: ', num2str(min(imageData)), ', Max: ', num2str(max(imageData))]);

                        % Calculate the mean pixel value for the current frame
                        meanPixelValue = mean(imageData);
                        % Get image dimensions and Reshape the data into 2D
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                        imageData2D = mat2gray(imageData2D); % Normalize to [0, 1]

                        %image8bit=im2uint8(imageData2D); % For Display Purposes only
                        % Initialize imageHandle once, then only update its
                        % data. It will help the speed
                        if capturedFrames(frameColor)==1
                            imageHandle{frameColor}=imshow(imageData2D, 'Parent', frameHandle(frameColor));
                            colormap(frameHandle(frameColor), colorMAP{frameColor});  % Apply custom blue colormap
                            axis image; % Keep aspect ratio
                        else
                            set(imageHandle{frameColor}, 'CData', imageData2D);  % Update image data
                        end

                        title(frameHandle(frameColor), sprintf('Frame %d ', actualFrameNumber));


                        % Update the mean pixel values buffer for UIAxes1 (limit to 100 frames)
                        framePixelValues{frameColor} = [framePixelValues{frameColor}, meanPixelValue];  % Append mean value
                        if numel(framePixelValues{frameColor}) > valueHistory
                            framePixelValues{frameColor} = framePixelValues{frameColor}(end-valueHistory+1:end);  % Keep last 100 frames
                        end

                        % Plot the last 100 frames' mean pixel values for UIAxes1
                        dF_F = 100*(framePixelValues{frameColor}-mean(framePixelValues{frameColor}))./(framePixelValues{frameColor});
                        plot(graphHandle(frameColor), 1:numel(framePixelValues{frameColor}), dF_F);
                        title(graphHandle(frameColor), 'Mean Pixel Value');
                        xlabel(graphHandle(frameColor), 'Frame Number');
                        ylabel(graphHandle(frameColor), 'dF/F(%)');

                        fileName = sprintf('image_%06d.tif', actualFrameNumber);
                        filePath =  fullfile(baseFolder,folderName{frameColor}, fileName);
                        %imwrite(imageData2D,filePath, 'Compression', 'none');
                        parfeval(@writeSingleFrame,0,imageData2D,filePath);
                        %delete(imageFrame);
                        if mod(frameCount, 10) == 0
                                set(imageHandle{frameColor}, 'CData', imageData2D); 
                            drawnow;  % Update the figure window, every 10 frames
                        end

                    end
                end
            end
                  
                fprintf(fileID, 'Total Acquired Frames: %d\n', frameCount);
                fprintf(fileID, 'Total Grabbed Frames: %d\n', actualFrameNumber);
                fprintf(fileID, 'Image Height: %d\n', imageHeight);
                fprintf(fileID, 'Image Width: %d\n', imageWidth);
                fprintf(fileID, 'End time: %s\n', datestr(now, 'yyyy-mm-dd_HH-MM-SS'));
                fclose(fileID)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stopping recording...');
    app.isRecording = false;
    app.isPreview = false;
    app.RecordButton.Enable = 'on';
    % Disarm and release the camera
    if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
        app.tlCamera.Disarm;
        app.tlCamera.Dispose;
    end
        end

        % Value changed function: GainKnob
        function GainChange(app, event)
        if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
        gainValue = app.GainKnob.Value; % Read the knob value
        mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
        app.tlCamera.Gain = mappedGain; % Set the gain
        currentGain = sprintf('Gain = %.2f', mappedGain); % Format the gain as a string
        disp(currentGain); % Display the gain value
        disp(['Knob Value: ', num2str(app.GainKnob.Value)]);
    else
        warning('Camera not initialized or not valid.');
    end
            
        end

        % Value changed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if app.PreviewButton.Value
                    disp('Starting continuous image acquisition.');
                     if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
                         app.tlCamera.IssueSoftwareTrigger;
                         app.isRecording = false;
                         app.isPreview = true;
                    disp('Preview started');
                     else
                         disp('camera already loaded...');
                    end
            else
                disp('Stopping preview...');
                app.isPreview = false;
                app.tlCamera.Disarm;
            end

        % Update preview
        while app.isPreview
            try
                imageFrame = app.tlCamera.GetPendingFrameOrNull;
                if ~isempty(imageFrame)
                    imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                    imageHeight = imageFrame.ImageData.Height_pixels;
                    imageWidth = imageFrame.ImageData.Width_pixels;
                    imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                    imageData2D = mat2gray(imageData2D);
                    imshow(imageData2D, 'Parent', app.Preview);
                    drawnow;
                end
            catch ME
                disp(['Error updating preview: ', ME.message]);
            end
        end
        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            exposureChange = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.ExposureTime_us = exposureChange;
            valueChange = sprintf('exposure changed to:',exposureChange);
            disp(valueChange);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LED1 = uiaxes(app.UIFigure);
            app.LED1.XTick = [];
            app.LED1.YTick = [];
            app.LED2 = uiaxes(app.UIFigure);
            zlabel(app.LED2, 'Z')
            app.LED2.XTick = [];
            app.LED2.YTick = [];
            app.LED3 = uiaxes(app.UIFigure);
            app.LED3.XTick = [];
            app.LED3.YTick = [];
            app.UIAxes1Graph = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1Graph, 'Z')
            app.UIAxes2Graph = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2Graph, 'Z')
            app.UIAxes3Graph = uiaxes(app.UIFigure);
            app.Preview = uiaxes(app.UIFigure);
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.LED1DropDownLabel = uilabel(app.UIFigure);
            app.LED1DropDownLabel.Text = 'LED 1';
            app.excitation1 = uidropdown(app.UIFigure);
            app.excitation1.Items = {'405nm', '470nm', '565nm', 'none'};
            app.excitation1.Value = '405nm';
            app.LED2Label = uilabel(app.UIFigure);
            app.LED2Label.Text = 'LED 2';
            app.excitation2 = uidropdown(app.UIFigure);
            app.excitation2.Items = {'405nm', '470nm', '565nm', 'none'};
            app.excitation2.Value = '470nm';
            app.LED3Label = uilabel(app.UIFigure);
            app.LED3Label.Text = 'LED 3';
            app.excitation3 = uidropdown(app.UIFigure);
            app.excitation3.Items = {'405nm', '470nm', '565nm', 'none'};
            app.excitation3.Value = '565nm';
            app.CameraSettingsPanel = uipanel(app.UIFigure);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.FrameRatechEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.FrameRatechEditFieldLabel.Text = 'Frame Rate/ch';
            app.FrameRatechEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.FrameRatechEditField.Value = 10;
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.ExposureTimemsEditField.Value = 100;
            app.BinSizepixelDropDownLabel = uilabel(app.CameraSettingsPanel);
            app.BinSizepixelDropDownLabel.Text = 'BinSize (pixel)';
            app.BinSizepixelDropDown = uidropdown(app.CameraSettingsPanel);
            app.BinSizepixelDropDown.Items = {'1', '2', '4', '8'};
            app.BinSizepixelDropDown.Value = '4';
            app.BufferSizeframesEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.BufferSizeframesEditFieldLabel.Text = 'Buffer Size (frames)';
            app.BufferSizeframesEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.BufferSizeframesEditField.Value = 100;
            app.GainKnobLabel = uilabel(app.CameraSettingsPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.CameraSettingsPanel, 'continuous');
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainChange, true);
            app.RecordingPanel = uipanel(app.UIFigure);
            app.RecordingPanel.Title = 'Recording';
            app.RecordButton = uibutton(app.RecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.ExperimenterEditFieldLabel = uilabel(app.RecordingPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.RecordingPanel, 'text');
            app.ExperimenterEditField.Value = 'Rob';
            app.SubjectEditFieldLabel = uilabel(app.RecordingPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordingPanel, 'text');
            app.SubjectEditField.Value = 'IM-1010';
            app.StopButton = uibutton(app.RecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.PreviewButton = uibutton(app.RecordingPanel, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.MesoscopeControlLabel = uilabel(app.UIFigure);
            app.MesoscopeControlLabel.Text = 'Mesoscope Control ';
            app.queSize = uilabel(app.UIFigure);
            app.queSize.Text = '';
            app.frameDrop = uilabel(app.UIFigure);
            app.frameDrop.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mesoscope_RCS_with_preview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end