classdef mesoscope_arduinoControl_RCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewSwitch                   matlab.ui.control.Switch
        PreviewSwitchLabel              matlab.ui.control.Label
        switch_565                      matlab.ui.control.Switch
        nmLEDLabel                      matlab.ui.control.Label
        switch_470                      matlab.ui.control.Switch
        nmLEDLabel_2                    matlab.ui.control.Label
        switch_405                      matlab.ui.control.Switch
        nmLEDSwitchLabel                matlab.ui.control.Label
        ArduinoPanel                    matlab.ui.container.Panel
        WaitingLabel                    matlab.ui.control.Label
        ArduinoFileDropDown             matlab.ui.control.DropDown
        ArduinoFileDropDownLabel        matlab.ui.control.Label
        ArduinoFolderEditField          matlab.ui.control.EditField
        ArduinoFolderEditFieldLabel     matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        ReleaseCOMPortsButton           matlab.ui.control.Button
        UploadtoArduinoButton           matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        frameDrop                       matlab.ui.control.Label
        queSize                         matlab.ui.control.Label
        MesoscopeControlLabel           matlab.ui.control.Label
        RecordingPanel                  matlab.ui.container.Panel
        BaseFolderEditField             matlab.ui.control.EditField
        BaseFolderEditFieldLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        CameraSettingsPanel             matlab.ui.container.Panel
        GainKnob                        matlab.ui.control.Knob
        GainKnobLabel                   matlab.ui.control.Label
        BufferSizeframesEditField       matlab.ui.control.NumericEditField
        BufferSizeframesEditFieldLabel  matlab.ui.control.Label
        BinSizepixelDropDown            matlab.ui.control.DropDown
        BinSizepixelDropDownLabel       matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
        hist_565                        matlab.ui.control.UIAxes
        hist_470                        matlab.ui.control.UIAxes
        hist_405                        matlab.ui.control.UIAxes
        histogram                       matlab.ui.control.UIAxes
        Preview                         matlab.ui.control.UIAxes
        Graph_565                       matlab.ui.control.UIAxes
        Graph_470                       matlab.ui.control.UIAxes
        Graph_405                       matlab.ui.control.UIAxes
        Image_565                       matlab.ui.control.UIAxes
        Image_470                       matlab.ui.control.UIAxes
        Image_405                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        frameCounter        % Frame counter
        isRecording = false; % Flag to control the loop
        tlCamera; % camera object
        isPreview = false; %flag to control Preview functionality
        actualFrameNumber; %define number of frames


        selpath = '' % Description
        serialPort=[];
        StopFlag = false;
        %avrdude_path = "/Users/ali/Library/Arduino15/packages/arduino/tools/dfu-util/0.11.0-arduino5/dfu-util";
        avrdude_path = '"C:\Users\Mohebi & Associates\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/bin/avrdude"';
        avrdude_config = '"C:\Users\Mohebi & Associates\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/etc/avrdude.conf"';
        trialFinished  = false;
        running = true;
        serialNumbers=[];
        tlCameraSDK=[];
        logFileID=[];

        % Define custom colormaps
        bfpColormap = [linspace(0, 0, 255)', linspace(0, 0, 255)', linspace(0, 1, 255)'];  % Custom blue
        gfpColormap = [linspace(0, 0, 255)', linspace(0, 1, 255)', linspace(0, 0, 255)'];  % Custom green
        rfpColormap = [linspace(0, 1, 255)', linspace(0, 0, 255)', linspace(0, 0, 255)'];  % Custom red

        valueHistory=100;   % How many frames to use for dF/F calculation

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load the Camera
            try
                ThorFolder = 'C:\Thorlabs\Scientific_Camera_Interfaces_Windows-2.1\Scientific Camera Interfaces\MATLAB';
                addpath(genpath(ThorFolder));
                cd(ThorFolder);
                NET.addAssembly([ThorFolder, '\Thorlabs.TSI.TLCamera.dll']);
                app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;
                app.serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;

                app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
                app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
                app.tlCamera.Arm;
                app.tlCamera.IssueSoftwareTrigger;
                disp('Camera initialized.');

            catch ME
                disp(['Error during startup: ', ME.message]);
            end

            %% Populate the Arduino file and folders
            X=serialportlist("available");
            ttyPorts = X(contains(X, 'COM'));
            app.selpath='C:\GitHub\mesoscope\Arduino Codes';
            cd('C:\GitHub\mesoscope')
            app.COMPortDropDown.Items=ttyPorts;
            file=dir([app.selpath,filesep,'*.hex'])
            for i=1:length(file)
                LOC{i}=file(i).name;
            end
            app.ArduinoFileDropDown.Items = LOC;
            for i=1:length(file)
                if (strcmp(LOC{i},'cameraControl.ino.hex')==1)
                    app.ArduinoFileDropDown.Value = app.ArduinoFileDropDown.Items{i};
                end
            end
            app.ArduinoFolderEditField.Value = app.selpath;
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)

            if strcmp(app.PreviewSwitch.Value, 'On')
                disp('Preview is enabled.');
                app.isPreview = true;
                while app.isPreview
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    if ~isempty(imageFrame)
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                        imageData2D = mat2gray(imageData2D);
                        imshow(imageData2D, 'Parent', app.Preview);
                        histogram(app.histogram,imageData,0:20:5000);
                        xlim(app.histogram,[0 5000])
                        delete(imageFrame);
                        drawnow;
                    end
                end
            else
                disp('Preview is disabled.');
                app.isPreview = false;
                % Add code to stop the preview
            end
            
        end

        % Button pushed function: UploadtoArduinoButton
        function UploadtoArduinoButtonPushed(app, event)

            app.isPreview = false;
            app.UploadtoArduinoButton.Visible = "off";
            app.PreviewSwitch.Enable="off";
            app.COMPortDropDown.Enable="off";
            app.ArduinoFolderEditField.Enable="off";
            app.ArduinoFileDropDown.Enable="off";

            selected_port = app.COMPortDropDown.Value;
            hex_file_path = [app.ArduinoFolderEditField.Value,filesep,app.ArduinoFileDropDown.Value];

            command = strcat(...
                app.avrdude_path, ...
                ' -C', app.avrdude_config, ...
                ' -v', ...
                ' -patmega328p', ...
                ' -carduino', ...
                ' -P', selected_port, ...
                ' -b115200', ...
                ' -D', ...
                ' "-Uflash:w:', hex_file_path, ':i"');


            [status,cmdout] = system(command);
            if contains(cmdout, 'avrdude done.') && status==0
                app.WaitingLabel.Text='Successfully Uploaded.'
                app.WaitingLabel.FontColor='b';
            else
                app.WaitingLabel.Text='Upload Failed. Try Again!'
                app.WaitingLabel.FontColor='r';
            end
            delete(instrfindall)
            pause(2)
            app.RecordButton.Visible = "on";
            app.CameraSettingsPanel.Enable = 'on';
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            %% Set camera Parameters
            app.tlCamera.Disarm; % Disarm the camera so we can update it's parameters such as Bin Size.
            BinSize = str2num(app.BinSizepixelDropDown.Value);
            app.tlCamera.ROIAndBin.BinX = BinSize;
            app.tlCamera.ROIAndBin.BinY = BinSize;
            app.tlCamera.MaximumNumberOfFramesToQueue = app.BufferSizeframesEditField.Value;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.ExposureTime_us = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.Arm;
        
            %% Update display enable/disable
            app.isRecording = true;
            app.isPreview = false;
            app.RecordButton.Enable = 'off';
            app.CameraSettingsPanel.Enable = "off";
            app.BaseFolderEditField.Enable="off";
            app.ExperimenterEditField.Enable = "off";
            app.SubjectEditField.Enable = "off";
            
            %% Pre-allocate variables
            frameHandle(1)=app.Image_405;frameHandle(2)=app.Image_470;frameHandle(3)=app.Image_565;
            graphHandle(1)=app.Graph_405;graphHandle(2)=app.Graph_470;graphHandle(3)=app.Graph_565;
            histHandle(1)=app.hist_405;histHandle(2)=app.hist_470;histHandle(3)=app.hist_565;
            colorMAP{1}=app.bfpColormap;colorMAP{2}=app.gfpColormap;colorMAP{3}=app.rfpColormap;
            for i = 1:3, framePixelValues{i} = nan(1, app.valueHistory); end
            folderName{1}='405';folderName{2}='470';folderName{3}='565';
            capturedFrames = zeros(1,3);

            baseDir = [app.BaseFolderEditField.Value filesep app.ExperimenterEditField.Value]; % Change this to your desired path
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            Subject_ID = app.SubjectEditField.Value;
            baseFolder = fullfile(baseDir, [Subject_ID '_' timestamp]);

            for i=1:3,mkdir([baseFolder filesep folderName{i}]);end

            % Initialize batch writing buffers (one buffer per channel)
            batchSize = 100;  % number of frames to buffer before writing
            frameBuffer = cell(1,3); % each element will hold a cell array of frames for that channel
            for i=1:3
                frameBuffer{i} = cell(batchSize, 1);
            end
            frameBufferCounter = [0 0 0]; % tracks how many frames are currently in each channel's buffer
            
            %% Start logging Camera Details
            camera_log_name=[baseFolder filesep Subject_ID  '_' timestamp '_camera_log.txt'];
            selected_port = app.COMPortDropDown.Value;
            mode = (strcmp(app.switch_565.Value, 'On') * 4) + ...
                (strcmp(app.switch_470.Value, 'On') * 2) + ...
                (strcmp(app.switch_405.Value, 'On') * 1);
            %% Flipping mode 3,4 so that mode 1:3 mean LEDs1:3 on
            if mode==4
                mode=3;
            elseif mode==3
                mode=4;
            end

            %% End of mode Flipping...
            
            camera_log_task = parfeval(backgroundPool,@updateCameraLogFromArduino, 0, selected_port, camera_log_name, mode);            
            parpool('local', 6); % Adjust the number of workers as needed
            
            fileID = fopen([baseFolder filesep Subject_ID  '_' timestamp '_config.txt'], 'a'); % Open file for writing
            fprintf(fileID, 'Start time: %s\n', timestamp);
            fprintf(fileID, 'Experimenter: %s\n', app.ExperimenterEditField.Value);
            fprintf(fileID, 'Subject ID: %s\n', app.SubjectEditField.Value);
            fprintf(fileID, 'Camera Model: %s\n', app.tlCamera.Model);
            fprintf(fileID, 'Frame Rate Control Value fps: %d\n', app.tlCamera.FrameRateControlValue_fps);
            fprintf(fileID, 'Average fps: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Bin Size: %d\n', BinSize);
            fprintf(fileID, 'Exposure Time (Requested): %d  ms\n', app.ExposureTimemsEditField.Value);
            fprintf(fileID, 'Exposure Time (Actual): %d  s\n', app.tlCamera.ExposureTime_us);
            fprintf(fileID, 'Frame Time (Actual): %d  us\n', app.tlCamera.FrameTime_us);
            fprintf(fileID, 'Sensor Readout Time (Actual): %d  ns\n', app.tlCamera.SensorReadoutTime_ns);
            fprintf(fileID, 'Sensor Pixel Width: %d  um\n', app.tlCamera.SensorPixelWidth_um);
            fprintf(fileID, 'Bit Depth: %d\n', app.tlCamera.BitDepth);
            fprintf(fileID, 'Black Level: %d\n', app.tlCamera.BlackLevel);
            fprintf(fileID, 'Frames Per Second: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Gain: %d\n', app.tlCamera.Gain);
            fprintf(fileID, 'OperationMode: %s\n', app.tlCamera.OperationMode);
            fprintf(fileID, 'HotPixel Correction Threshold: %d\n', app.tlCamera.HotPixelCorrectionThreshold);
            fprintf(fileID, '405 LED: %s\n', app.switch_405.Value);
            fprintf(fileID, '470 LED: %s\n', app.switch_470.Value);
            fprintf(fileID, '565 LED: %s\n', app.switch_565.Value);
            fprintf(fileID, 'Image Height: %d\n', app.tlCamera.ImageHeight_pixels);
            fprintf(fileID, 'Image Width: %d\n', app.tlCamera.ImageWidth_pixels);

            %% Start Imaging
            app.StopButton.Visible = 'on';
            app.tlCamera.IssueSoftwareTrigger;
            disp('Starting continuous image acquisition.');
            frameCount = 0;
            while app.isRecording
                % Check if image buffer has been filled
                NumberOfQueuedFrames = app.tlCamera.NumberOfQueuedFrames;
                app.queSize.Text= ['Frames remaining in the buffer: ', num2str(NumberOfQueuedFrames)];

                if (NumberOfQueuedFrames > 0)

                    imageFrame  = app.tlCamera.GetPendingFrameOrNull;
                    actualFrameNumber = imageFrame.FrameNumber;
                    app.frameDrop.Text= ['Dropped Frames: ', num2str(imageFrame.FrameNumber-frameCount)];
                    frameCount = frameCount + 1;

                    if ~isempty(imageFrame)
                        %% Determine the frame Color
                        switch mode
                            case {1, 2, 3}
                                frameColor = mode;
                            case 4
                                frameColor = 1 + mod(actualFrameNumber, 2);
                            case 5
                                frameColor = 1 + 2 * mod(actualFrameNumber, 2);
                            case 6
                                frameColor = 2 + mod(actualFrameNumber, 2);
                            case 7
                                frameColor = 1 + mod(actualFrameNumber, 3);
                        end

                        
                        capturedFrames(frameColor) = capturedFrames(frameColor)+1;

                        %% Process Image data
                        % Get the image data as 1D uint16 array
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        % Get image dimensions and Reshape the data into 2D
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                        imageData2D = mat2gray(imageData2D); % Normalize to [0, 1]

                        % Update Display every 10 frames for speed
                        if capturedFrames(frameColor)==1
                            imageHandle{frameColor}=imshow(imageData2D, 'Parent', frameHandle(frameColor));
                            colormap(frameHandle(frameColor), colorMAP{frameColor});  % Apply custom blue colormap
                            axis(frameHandle(frameColor), 'image'); % Keep aspect ratio
                        elseif mod(frameCount,10)==0
                            set(imageHandle{frameColor}, 'CData', imageData2D);  % Update image data
                            drawnow;
                        end

                        title(frameHandle(frameColor), sprintf('Frame %d ', actualFrameNumber));

                        %% Update the dF/F graph
                        % Calculate the mean pixel value for the current frame
                        meanPixelValue = mean(imageData);
                        % Update the mean pixel values buffer for UIAxes1 (limit to 100 frames)
                        framePixelValues{frameColor} = [framePixelValues{frameColor}, meanPixelValue];  % Append mean value
                        if numel(framePixelValues{frameColor}) > app.valueHistory
                            framePixelValues{frameColor} = framePixelValues{frameColor}(end-app.valueHistory+1:end);  % Keep last 100 frames
                        end

                        % Plot the last 100 frames' mean pixel values for UIAxes1
                        dF_F = 100*(framePixelValues{frameColor}-mean(framePixelValues{frameColor}))./(framePixelValues{frameColor});
                        plot(graphHandle(frameColor), 1:numel(framePixelValues{frameColor}), dF_F);
                        title(graphHandle(frameColor), 'Mean Pixel Value');
                        xlabel(graphHandle(frameColor), 'Frame Number');
                        ylabel(graphHandle(frameColor), 'dF/F(%)');

                        histogram(histHandle(frameColor),imageData,0:20:5000);
                        xlim(histHandle(frameColor),[0 5000])

                        % Add current frame to buffer
                        frameBufferCounter(frameColor) = frameBufferCounter(frameColor) + 1;
                        frameBuffer{frameColor}{frameBufferCounter(frameColor)} = struct(...
                            'FrameNumber', actualFrameNumber, ...
                            'Image', imageData2D ...
                            );

                     % If buffer reaches batchSize, offload to parallel writing
            if frameBufferCounter(frameColor) == batchSize
                % Capture the batch
                currentBatch = frameBuffer{frameColor};
                channelFolder = fullfile(baseFolder, folderName{frameColor});

                % Reset buffer
                frameBuffer{frameColor} = cell(batchSize, 1);
                frameBufferCounter(frameColor) = 0;

                % Offload writing of this batch to a background worker
                parfeval(@writeFrameBatch, 0, currentBatch, channelFolder);
            end

            delete(imageFrame);
                    end
                end
            end


            fprintf(fileID, 'Total Acquired Frames: %d\n', frameCount);
            fprintf(fileID, 'Total Grabbed Frames: %d\n', actualFrameNumber);
            fprintf(fileID, 'End time: %s\n', datestr(now, 'yyyy-mm-dd_HH-MM-SS'));
            fclose(fileID)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stopping recording...');
            app.isRecording = false;
            app.isPreview = false;
            app.StopButton.Enable = "off";
            % Disarm and release the camera
            app.tlCamera.Disarm;
            delete(instrfindall)
            if ~isempty(gcp('nocreate')) delete(gcp('nocreate')); end  % Close parallel processing
            disp('Recording Stopped');
        end

        % Button pushed function: ReleaseCOMPortsButton
        function ReleaseCOMPortsButtonPushed(app, event)
            delete(instrfindall)

            X=serialportlist("available");
            if ismac
                ttyPorts = X(contains(X, 'tty.usb'));
            else
                ttyPorts = X(contains(X, 'COM'));
            end
            app.COMPortDropDown.Items=ttyPorts;
            app.WaitingLabel.Text = 'Waiting...';
            app.WaitingLabel.FontColor = 'm';
            app.UploadtoArduinoButton.Visible = "on";
            app.PreviewSwitch.Enable="on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Get the current parallel pool
            pool = gcp('nocreate');
            % Check if a pool exists and delete it
            if ~isempty(pool)
                delete(pool);
            end
            if app.tlCamera.IsArmed
                app.tlCamera.Disarm;
            end
            app.tlCamera.Dispose;
            delete(app.tlCamera);
            % Release the serial numbers
            delete(app.serialNumbers);
            % Release the TLCameraSDK.
            app.tlCameraSDK.Dispose;
            delete(app.tlCameraSDK);
            delete(app);
            delete(app.UIFigure); % This will close the app's main figure window
        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            exposureChange = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.ExposureTime_us = exposureChange;
            valueChange = sprintf('exposure changed to:',exposureChange);
            disp(valueChange);
        end

        % Value changed function: GainKnob
        function GainChange(app, event)
            if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
                gainValue = app.GainKnob.Value; % Read the knob value
                mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
                app.tlCamera.Gain = mappedGain; % Set the gain
                currentGain = sprintf('Gain = %.2f', mappedGain); % Format the gain as a string
                disp(currentGain); % Display the gain value
                disp(['Knob Value: ', num2str(app.GainKnob.Value)]);
            else
                warning('Camera not initialized or not valid.');
            end

        end

        % Value changed function: switch_405
        function switch_405ValueChanged(app, event)
            value = app.switch_405.Value;
            app.Graph_405.Visible=value;
            app.Image_405.Visible=value;
            app.hist_405.Visible=value;
        end

        % Value changed function: switch_470
        function switch_470ValueChanged(app, event)
            value = app.switch_470.Value;
            app.Graph_470.Visible=value;
            app.Image_470.Visible=value;
            app.hist_470.Visible=value;
        end

        % Value changed function: switch_565
        function switch_565ValueChanged(app, event)
            value = app.switch_565.Value;
            app.Graph_565.Visible=value;
            app.Image_565.Visible=value;
            app.hist_565.Visible=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image_405 = uiaxes(app.UIFigure);
            app.Image_405.XTick = [];
            app.Image_405.YTick = [];
            app.Image_470 = uiaxes(app.UIFigure);
            zlabel(app.Image_470, 'Z')
            app.Image_470.XTick = [];
            app.Image_470.YTick = [];
            app.Image_565 = uiaxes(app.UIFigure);
            app.Image_565.XTick = [];
            app.Image_565.YTick = [];
            app.Graph_405 = uiaxes(app.UIFigure);
            zlabel(app.Graph_405, 'Z')
            app.Graph_470 = uiaxes(app.UIFigure);
            zlabel(app.Graph_470, 'Z')
            app.Graph_565 = uiaxes(app.UIFigure);
            app.Preview = uiaxes(app.UIFigure);
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.histogram = uiaxes(app.UIFigure);
            title(app.histogram, 'Histogram')
            xlabel(app.histogram, 'X')
            ylabel(app.histogram, 'Y')
            zlabel(app.histogram, 'Z')
            app.hist_405 = uiaxes(app.UIFigure);
            xlabel(app.hist_405, 'X')
            ylabel(app.hist_405, 'Y')
            zlabel(app.hist_405, 'Z')
            app.hist_470 = uiaxes(app.UIFigure);
            xlabel(app.hist_470, 'X')
            ylabel(app.hist_470, 'Y')
            zlabel(app.hist_470, 'Z')
            app.hist_565 = uiaxes(app.UIFigure);
            xlabel(app.hist_565, 'X')
            ylabel(app.hist_565, 'Y')
            zlabel(app.hist_565, 'Z')
            app.CameraSettingsPanel = uipanel(app.UIFigure);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.ExposureTimemsEditField.Value = 30;
            app.BinSizepixelDropDownLabel = uilabel(app.CameraSettingsPanel);
            app.BinSizepixelDropDownLabel.Enable = 'off';
            app.BinSizepixelDropDownLabel.Text = 'BinSize (pixel)';
            app.BinSizepixelDropDown = uidropdown(app.CameraSettingsPanel);
            app.BinSizepixelDropDown.Items = {'1', '2', '4', '8'};
            app.BinSizepixelDropDown.Enable = 'off';
            app.BinSizepixelDropDown.Value = '4';
            app.BufferSizeframesEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.BufferSizeframesEditFieldLabel.Text = 'Buffer Size (frames)';
            app.BufferSizeframesEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.BufferSizeframesEditField.Value = 100;
            app.GainKnobLabel = uilabel(app.CameraSettingsPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.CameraSettingsPanel, 'continuous');
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainChange, true);
            app.RecordingPanel = uipanel(app.UIFigure);
            app.RecordingPanel.Title = 'Recording';
            app.RecordButton = uibutton(app.RecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Visible = 'off';
            app.RecordButton.Text = 'Record';
            app.ExperimenterEditFieldLabel = uilabel(app.RecordingPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.RecordingPanel, 'text');
            app.ExperimenterEditField.Value = 'Rob';
            app.SubjectEditFieldLabel = uilabel(app.RecordingPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordingPanel, 'text');
            app.SubjectEditField.Value = 'IM-1010';
            app.StopButton = uibutton(app.RecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'STOP';
            app.BaseFolderEditFieldLabel = uilabel(app.RecordingPanel);
            app.BaseFolderEditFieldLabel.Text = 'Base Folder';
            app.BaseFolderEditField = uieditfield(app.RecordingPanel, 'text');
            app.BaseFolderEditField.Value = 'E:\imaging';
            app.MesoscopeControlLabel = uilabel(app.UIFigure);
            app.MesoscopeControlLabel.Text = 'Mesoscope Control ';
            app.queSize = uilabel(app.UIFigure);
            app.queSize.Text = '';
            app.frameDrop = uilabel(app.UIFigure);
            app.frameDrop.Text = '';
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.BrowseButton = uibutton(app.ArduinoPanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.UploadtoArduinoButton = uibutton(app.ArduinoPanel, 'push');
            app.UploadtoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoArduinoButtonPushed, true);
            app.UploadtoArduinoButton.Text = 'Upload to Arduino';
            app.ReleaseCOMPortsButton = uibutton(app.ArduinoPanel, 'push');
            app.ReleaseCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseCOMPortsButtonPushed, true);
            app.ReleaseCOMPortsButton.Text = 'Release COM Ports';
            app.COMPortDropDownLabel = uilabel(app.ArduinoPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel.Text = 'Arduino Folder';
            app.ArduinoFolderEditField = uieditfield(app.ArduinoPanel, 'text');
            app.ArduinoFolderEditField.Value = 'Select your folder';
            app.ArduinoFileDropDownLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFileDropDownLabel.Text = 'Arduino File';
            app.ArduinoFileDropDown = uidropdown(app.ArduinoPanel);
            app.WaitingLabel = uilabel(app.ArduinoPanel);
            app.WaitingLabel.Text = 'Waiting...';
            app.nmLEDSwitchLabel = uilabel(app.UIFigure);
            app.nmLEDSwitchLabel.Text = '405nm LED';
            app.switch_405 = uiswitch(app.UIFigure, 'slider');
            app.switch_405.ValueChangedFcn = createCallbackFcn(app, @switch_405ValueChanged, true);
            app.switch_405.Value = 'On';
            app.nmLEDLabel_2 = uilabel(app.UIFigure);
            app.nmLEDLabel_2.Text = '470nm LED';
            app.switch_470 = uiswitch(app.UIFigure, 'slider');
            app.switch_470.ValueChangedFcn = createCallbackFcn(app, @switch_470ValueChanged, true);
            app.switch_470.Value = 'On';
            app.nmLEDLabel = uilabel(app.UIFigure);
            app.nmLEDLabel.Text = '565nm LED';
            app.switch_565 = uiswitch(app.UIFigure, 'slider');
            app.switch_565.ValueChangedFcn = createCallbackFcn(app, @switch_565ValueChanged, true);
            app.switch_565.Value = 'On';
            app.PreviewSwitchLabel = uilabel(app.UIFigure);
            app.PreviewSwitchLabel.Text = 'Preview';
            app.PreviewSwitch = uiswitch(app.UIFigure, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mesoscope_arduinoControl_RCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end