classdef mesoscope_control_RCS_Batch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewSwitch                   matlab.ui.control.Switch
        PreviewSwitchLabel              matlab.ui.control.Label
        ArduinoPanel                    matlab.ui.container.Panel
        WaitingLabel                    matlab.ui.control.Label
        ArduinoFileDropDown             matlab.ui.control.DropDown
        ArduinoFileDropDownLabel        matlab.ui.control.Label
        ArduinoFolderEditField          matlab.ui.control.EditField
        ArduinoFolderEditFieldLabel     matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        ReleaseCOMPortsButton           matlab.ui.control.Button
        UploadtoArduinoButton           matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        frameDrop                       matlab.ui.control.Label
        queSize                         matlab.ui.control.Label
        MesoscopeControlLabel           matlab.ui.control.Label
        RecordingPanel                  matlab.ui.container.Panel
        switch_565                      matlab.ui.control.Switch
        nmLEDLabel                      matlab.ui.control.Label
        switch_470                      matlab.ui.control.Switch
        nmLEDLabel_2                    matlab.ui.control.Label
        switch_405                      matlab.ui.control.Switch
        nmLEDSwitchLabel                matlab.ui.control.Label
        BaseFolderEditField             matlab.ui.control.EditField
        BaseFolderEditFieldLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        CameraSettingsPanel             matlab.ui.container.Panel
        GainKnob                        matlab.ui.control.Knob
        GainKnobLabel                   matlab.ui.control.Label
        BufferSizeframesEditField       matlab.ui.control.NumericEditField
        BufferSizeframesEditFieldLabel  matlab.ui.control.Label
        BinSizepixelDropDown            matlab.ui.control.DropDown
        BinSizepixelDropDownLabel       matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        frameCounter        % Frame counter
        isRecording = false; % Flag to control the loop
        tlCamera; % camera object
        isPreview = false; %flag to control Preview functionality
        actualFrameNumber; %define number of frames


        selpath = '' % Description
        serialPort=[];
        StopFlag = false;
        %avrdude_path = "/Users/ali/Library/Arduino15/packages/arduino/tools/dfu-util/0.11.0-arduino5/dfu-util";
        avrdude_path = '"C:\Users\Mohebi & Associates\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/bin/avrdude"';
        avrdude_config = '"C:\Users\Mohebi & Associates\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/etc/avrdude.conf"';
        trialFinished  = false;
        running = true;
        serialNumbers=[];
        tlCameraSDK=[];
        logFileID=[];

        % Define custom colormaps
        bfpColormap = [linspace(0, 0, 255)', linspace(0, 0, 255)', linspace(0, 1, 255)'];  % Custom blue
        gfpColormap = [linspace(0, 0, 255)', linspace(0, 1, 255)', linspace(0, 0, 255)'];  % Custom green
        rfpColormap = [linspace(0, 1, 255)', linspace(0, 0, 255)', linspace(0, 0, 255)'];  % Custom red

        valueHistory=100;   % How many frames to use for dF/F calculation

        FigureHandle=nan(1,4);
        ImageAxes;
        HistogramAxes;

        graphHandle=nan;
        trace_axes;
    end
    
    methods (Access = private)
        

        % Helper function to write a single frame
        
       
        function createFigure(app,figNumber)

            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Define figure dimensions
            figWidth = 0.20 * screenWidth;
            figHeight = 0.80 * screenHeight;

            % Positions for the three figures
            pos(1,:) = [0, 0.10 * screenHeight, figWidth, figHeight]; % Left
            pos(2,:) = [0.20 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Center
            pos(3,:) = [0.40 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right
            pos(4,:) = [0.65 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right
            
            figureTitle={"Isosbestic Channel","Green Channel","Red Channel","Preview"}
            
            % Create a new figure window
            app.FigureHandle(figNumber) = figure('Name', figureTitle{figNumber},'Position', pos(figNumber,:));

            % Create two axes in the new figure with custom positions
            app.ImageAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.3, 0.8, 0.65]);
            app.HistogramAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.05, 0.8, 0.2]);
   
        end
        
        function createGraph(app)
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Define figure dimensions
            figWidth = 0.4 * screenWidth;
            figHeight = 0.80 * screenHeight;

            % Positions for the three figures
            pos = [0.60* screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Left
                    
            
            % Create a new figure window
            app.graphHandle= figure('Name', "Raw Traces",'Position', pos);

            % Create three axes in the new figure with custom positions
            app.trace_axes{1} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.6, 0.8, 0.25]);
            app.trace_axes{2} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.35, 0.8, 0.25]);
            app.trace_axes{3} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.1, 0.8, 0.25]);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load the Camera
            try
                ThorFolder = 'C:\Thorlabs\Scientific_Camera_Interfaces_Windows-2.1\Scientific Camera Interfaces\MATLAB';
                addpath(genpath(ThorFolder));
                cd(ThorFolder);
                NET.addAssembly([ThorFolder, '\Thorlabs.TSI.TLCamera.dll']);
                app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;
                app.serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;

                app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
                app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
                app.tlCamera.Arm;
                app.tlCamera.IssueSoftwareTrigger;
                disp('Camera initialized.');

            catch ME
                disp(['Error during startup: ', ME.message]);
            end

            %% Populate the Arduino file and folders
            X=serialportlist("available");
            ttyPorts = X(contains(X, 'COM'));
            app.selpath='C:\GitHub\mesoscope\Arduino Codes';
            cd('C:\GitHub\mesoscope')
            app.COMPortDropDown.Items=ttyPorts;
            file=dir([app.selpath,filesep,'*.hex'])
            for i=1:length(file)
                LOC{i}=file(i).name;
            end
            app.ArduinoFileDropDown.Items = LOC;
            for i=1:length(file)
                if (strcmp(LOC{i},'cameraControl.ino.hex')==1)
                    app.ArduinoFileDropDown.Value = app.ArduinoFileDropDown.Items{i};
                end
            end
            app.ArduinoFolderEditField.Value = app.selpath;
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)

            if strcmp(app.PreviewSwitch.Value, 'On')
                disp('Preview is enabled.');
                app.isPreview = true;
                figNumber=4;
                if isnan(app.FigureHandle(figNumber)) || ~isgraphics(app.FigureHandle(figNumber))
                    createFigure(app,figNumber);
                end
                while app.isPreview
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    if (~isempty(imageFrame) && isvalid(app.ImageAxes{figNumber}))
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                        imageData2D = mat2gray(imageData2D);
                        imshow(imageData2D, 'Parent', app.ImageAxes{figNumber});
                        histogram(app.HistogramAxes{figNumber},imageData,0:20:5000);
                        xlim(app.HistogramAxes{figNumber},[0 5000])
                        title(app.HistogramAxes{figNumber}, 'Histogram');
                        xlabel(app.HistogramAxes{figNumber}, 'Pixel Intensity');
                        ylabel(app.HistogramAxes{figNumber}, 'Count');
                        delete(imageFrame);
                        drawnow;
                    end
                end
            else
                disp('Preview is disabled.');
                app.isPreview = false;
                % Add code to stop the preview
            end
            
        end

        % Button pushed function: UploadtoArduinoButton
        function UploadtoArduinoButtonPushed(app, event)

            app.isPreview = false;
            app.UploadtoArduinoButton.Visible = "off";
            app.PreviewSwitch.Enable="off";
            app.COMPortDropDown.Enable="off";
            app.ArduinoFolderEditField.Enable="off";
            app.ArduinoFileDropDown.Enable="off";

            selected_port = app.COMPortDropDown.Value;
            hex_file_path = [app.ArduinoFolderEditField.Value,filesep,app.ArduinoFileDropDown.Value];

            command = strcat(...
                app.avrdude_path, ...
                ' -C', app.avrdude_config, ...
                ' -v', ...
                ' -patmega328p', ...
                ' -carduino', ...
                ' -P', selected_port, ...
                ' -b115200', ...
                ' -D', ...
                ' "-Uflash:w:', hex_file_path, ':i"');


            [status,cmdout] = system(command);
            if contains(cmdout, 'avrdude done.') && status==0
                app.WaitingLabel.Text='Successfully Uploaded.'
                app.WaitingLabel.FontColor='b';
            else
                app.WaitingLabel.Text='Upload Failed. Try Again!'
                app.WaitingLabel.FontColor='r';
            end
            delete(instrfindall)
            pause(2)
            app.RecordButton.Visible = "on";
            app.CameraSettingsPanel.Enable = 'on';
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

            %% Camera Parameters
            app.tlCamera.Disarm; 
            BinSize = str2num(app.BinSizepixelDropDown.Value);
            app.tlCamera.ROIAndBin.BinX = BinSize;
            app.tlCamera.ROIAndBin.BinY = BinSize;
            app.tlCamera.MaximumNumberOfFramesToQueue = app.BufferSizeframesEditField.Value;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.ExposureTime_us = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.Arm;

            %% Update UI
            app.isRecording = true;
            app.isPreview = false;
            app.RecordButton.Enable = 'off';
            app.CameraSettingsPanel.Enable = "off";
            app.BaseFolderEditField.Enable="off";
            app.ExperimenterEditField.Enable = "off";
            app.SubjectEditField.Enable = "off";

            %% Pre-allocate variables
            colorMAP{1}=app.bfpColormap; colorMAP{2}=app.gfpColormap; colorMAP{3}=app.rfpColormap;
            title_trace={"405nm","470nm","565nm"};
            for i = 1:3, framePixelValues{i} = nan(1, app.valueHistory); end
            folderName{1}='405';folderName{2}='470';folderName{3}='565';
            capturedFrames = zeros(1,3);
            COLORS_trace=[0 0 0;0 1 0;1 0 0];

            baseDir = [app.BaseFolderEditField.Value filesep app.ExperimenterEditField.Value]; 
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            Subject_ID = app.SubjectEditField.Value;
            baseFolder = fullfile(baseDir, [Subject_ID '_' timestamp]);

            for i=1:3,mkdir([baseFolder filesep folderName{i}]);end

            %% Camera Log
            camera_log_name=[baseFolder filesep Subject_ID  '_' timestamp '_camera_log.txt'];
            selected_port = app.COMPortDropDown.Value;
            mode = (strcmp(app.switch_565.Value, 'On') * 4) + ...
                (strcmp(app.switch_470.Value, 'On') * 2) + ...
                (strcmp(app.switch_405.Value, 'On') * 1);
            if mode==4
                mode=3;
            elseif mode==3
                mode=4;
            end
            camera_log_task = parfeval(backgroundPool,@updateCameraLogFromArduino, 0,selected_port, camera_log_name, mode);            
            parpool("Threads",8)

            fileID = fopen([baseFolder filesep Subject_ID  '_' timestamp '_config.txt'], 'a'); 
            fprintf(fileID, 'Start time: %s\n', timestamp);
            fprintf(fileID, 'Experimenter: %s\n', app.ExperimenterEditField.Value);
            fprintf(fileID, 'Subject ID: %s\n', app.SubjectEditField.Value);
            fprintf(fileID, 'Camera Model: %s\n', app.tlCamera.Model);
            fprintf(fileID, 'Frame Rate Control Value fps: %d\n', app.tlCamera.FrameRateControlValue_fps);
            fprintf(fileID, 'Average fps: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Bin Size: %d\n', BinSize);
            fprintf(fileID, 'Exposure Time (Requested): %d ms\n', app.ExposureTimemsEditField.Value);
            fprintf(fileID, 'Exposure Time (Actual): %d us\n', app.tlCamera.ExposureTime_us);
            fprintf(fileID, 'Frame Time (Actual): %d us\n', app.tlCamera.FrameTime_us);
            fprintf(fileID, 'Sensor Readout Time (Actual): %d ns\n', app.tlCamera.SensorReadoutTime_ns);
            fprintf(fileID, 'Sensor Pixel Width: %d um\n', app.tlCamera.SensorPixelWidth_um);
            fprintf(fileID, 'Bit Depth: %d\n', app.tlCamera.BitDepth);
            fprintf(fileID, 'Black Level: %d\n', app.tlCamera.BlackLevel);
            fprintf(fileID, 'Frames Per Second: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Gain: %d\n', app.tlCamera.Gain);
            fprintf(fileID, 'OperationMode: %s\n', app.tlCamera.OperationMode);
            fprintf(fileID, 'HotPixel Correction Threshold: %d\n', app.tlCamera.HotPixelCorrectionThreshold);
            fprintf(fileID, '405 LED: %s\n', app.switch_405.Value);
            fprintf(fileID, '470 LED: %s\n', app.switch_470.Value);
            fprintf(fileID, '565 LED: %s\n', app.switch_565.Value);
            fprintf(fileID, 'Image Height: %d\n', app.tlCamera.ImageHeight_pixels);
            fprintf(fileID, 'Image Width: %d\n', app.tlCamera.ImageWidth_pixels);

            app.StopButton.Visible = 'on';
            app.tlCamera.IssueSoftwareTrigger;
            disp('Starting continuous image acquisition.');
            frameCount = 0;

            createFigure(app,1);createFigure(app,2);createFigure(app,3);
            createGraph(app);

            %% Modified for batch writing
            batchSize = 200;  
            frameBuffer = cell(1,3); 
            for i=1:3
                frameBuffer{i} = cell(batchSize,1);
            end
            frameBufferCounter = [0 0 0];

            while app.isRecording
                NumberOfQueuedFrames = app.tlCamera.NumberOfQueuedFrames;
                app.queSize.Text= ['Frames remaining in the buffer: ', num2str(NumberOfQueuedFrames)];

                if (NumberOfQueuedFrames > 0)
                    imageFrame  = app.tlCamera.GetPendingFrameOrNull;
                    if ~isempty(imageFrame)
                        actualFrameNumber = imageFrame.FrameNumber;
                        app.frameDrop.Text= ['Dropped Frames: ', num2str(imageFrame.FrameNumber-frameCount)];
                        frameCount = frameCount + 1;

                        % Determine frameColor
                        switch mode
                            case {1, 2, 3}
                                frameColor = mode;
                            case 4
                                frameColor = 1 + mod(actualFrameNumber, 2);
                            case 5
                                frameColor = 1 + 2 * mod(actualFrameNumber, 2);
                            case 6
                                frameColor = 2 + mod(actualFrameNumber, 2);
                            case 7
                                frameColor = 1 + mod(actualFrameNumber, 3);
                        end

                        capturedFrames(frameColor) = capturedFrames(frameColor)+1;

                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);
                        imageData2D = mat2gray(imageData2D);

                        if capturedFrames(frameColor)==1
                            imageHandle{frameColor}=imshow(imageData2D, 'Parent', app.ImageAxes{frameColor});
                            colormap(app.ImageAxes{frameColor}, colorMAP{frameColor});
                            axis(app.ImageAxes{frameColor}, 'image');
                        else
                            if mod(frameCount, 10) == 0
                                set(imageHandle{frameColor}, 'CData', imageData2D);  
                            end
                        end

                        title(app.ImageAxes{frameColor}, sprintf('Frame %d ', actualFrameNumber));

                        meanPixelValue = mean(imageData);
                        framePixelValues{frameColor} = [framePixelValues{frameColor}, meanPixelValue];
                        if numel(framePixelValues{frameColor}) > app.valueHistory
                            framePixelValues{frameColor} = framePixelValues{frameColor}(end-app.valueHistory+1:end);
                        end
                        
                        dF_F = 100*(framePixelValues{frameColor}-mean(framePixelValues{frameColor})) ./ (framePixelValues{frameColor});
                        plot(app.trace_axes{frameColor}, 1:numel(framePixelValues{frameColor}), dF_F,"Color",COLORS_trace(frameColor,:),"LineWidth",2);
                        title(app.trace_axes{frameColor}, title_trace{frameColor},"FontSize",16);
                        xlabel(app.trace_axes{frameColor}, 'Frame Number');
                        ylabel(app.trace_axes{frameColor}, 'dF/F(%)');
                        set(app.trace_axes{frameColor},"XTICK",[])

                        histogram(app.HistogramAxes{frameColor},imageData,0:20:5000);
                        xlim(app.HistogramAxes{frameColor},[0 5000])

                        %%% Batch writing %%%
                        frameBufferCounter(frameColor) = frameBufferCounter(frameColor) + 1;
                        frameBuffer{frameColor}{frameBufferCounter(frameColor)} = struct(...
                            'FrameNumber', actualFrameNumber, ...
                            'Image', imageData2D ...
                        );

                        if frameBufferCounter(frameColor) == batchSize
                            currentBatch = frameBuffer{frameColor};
                            channelFolder = fullfile(baseFolder, folderName{frameColor});
                            frameBuffer{frameColor} = cell(batchSize,1);
                            frameBufferCounter(frameColor)=0;
                            parfeval(backgroundPool,@writeFrameBatch,0,currentBatch,channelFolder);
                        end

                        delete(imageFrame);

                        if mod(frameCount, 10) == 0
                            drawnow;  
                        end
                    end
                end
            end

            % Write leftover frames
            for ch = 1:3
                if frameBufferCounter(ch) > 0
                    leftoverBatch = frameBuffer{ch}(1:frameBufferCounter(ch));
                    channelFolder = fullfile(baseFolder, folderName{ch});
                    writeFrameBatch(leftoverBatch, channelFolder);
                end
            end
            %%% End batch logic %%%

            fprintf(fileID, 'Total Acquired Frames: %d\n', frameCount);
            fprintf(fileID, 'Total Grabbed Frames: %d\n', actualFrameNumber);
            fprintf(fileID, 'End time: %s\n', datestr(now, 'yyyy-mm-dd_HH-MM-SS'));
            fclose(fileID);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stopping recording...');
            app.isRecording = false;
            app.isPreview = false;
            app.StopButton.Enable = "off";
            % Disarm and release the camera
            app.tlCamera.Disarm;
            delete(instrfindall)
            if ~isempty(gcp('nocreate')) delete(gcp('nocreate')); end  % Close parallel processing
            disp('Recording Stopped');
        end

        % Button pushed function: ReleaseCOMPortsButton
        function ReleaseCOMPortsButtonPushed(app, event)
            delete(instrfindall)

            X=serialportlist("available");
            if ismac
                ttyPorts = X(contains(X, 'tty.usb'));
            else
                ttyPorts = X(contains(X, 'COM'));
            end
            app.COMPortDropDown.Items=ttyPorts;
            app.WaitingLabel.Text = 'Waiting...';
            app.WaitingLabel.FontColor = 'm';
            app.UploadtoArduinoButton.Visible = "on";
            app.PreviewSwitch.Enable="on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Get the current parallel pool
            pool = gcp('nocreate');
            % Check if a pool exists and delete it
            if ~isempty(pool)
                delete(pool);
            end
            if app.tlCamera.IsArmed
                app.tlCamera.Disarm;
            end
            app.tlCamera.Dispose;
            delete(app.tlCamera);
            % Release the serial numbers
            delete(app.serialNumbers);
            % Release the TLCameraSDK.
            app.tlCameraSDK.Dispose;
            delete(app.tlCameraSDK);
            delete(app);
            delete(app.UIFigure); % This will close the app's main figure window
        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            exposureChange = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.ExposureTime_us = exposureChange;
            valueChange = sprintf('exposure changed to:',exposureChange);
            disp(valueChange);
        end

        % Value changed function: GainKnob
        function GainChange(app, event)
            if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
                gainValue = app.GainKnob.Value; % Read the knob value
                mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
                app.tlCamera.Gain = mappedGain; % Set the gain
                currentGain = sprintf('Gain = %.2f', mappedGain); % Format the gain as a string
                disp(currentGain); % Display the gain value
                disp(['Knob Value: ', num2str(app.GainKnob.Value)]);
            else
                warning('Camera not initialized or not valid.');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CameraSettingsPanel = uipanel(app.UIFigure);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.ExposureTimemsEditField.Value = 50;
            app.BinSizepixelDropDownLabel = uilabel(app.CameraSettingsPanel);
            app.BinSizepixelDropDownLabel.Enable = 'off';
            app.BinSizepixelDropDownLabel.Text = 'BinSize (pixel)';
            app.BinSizepixelDropDown = uidropdown(app.CameraSettingsPanel);
            app.BinSizepixelDropDown.Items = {'1', '2', '4', '8'};
            app.BinSizepixelDropDown.Enable = 'off';
            app.BinSizepixelDropDown.Value = '4';
            app.BufferSizeframesEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.BufferSizeframesEditFieldLabel.Text = 'Buffer Size (frames)';
            app.BufferSizeframesEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.BufferSizeframesEditField.Value = 500;
            app.GainKnobLabel = uilabel(app.CameraSettingsPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.CameraSettingsPanel, 'continuous');
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainChange, true);
            app.RecordingPanel = uipanel(app.UIFigure);
            app.RecordingPanel.Title = 'Recording';
            app.RecordButton = uibutton(app.RecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Visible = 'off';
            app.RecordButton.Text = 'Record';
            app.ExperimenterEditFieldLabel = uilabel(app.RecordingPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.RecordingPanel, 'text');
            app.ExperimenterEditField.Value = 'Rob';
            app.SubjectEditFieldLabel = uilabel(app.RecordingPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordingPanel, 'text');
            app.SubjectEditField.Value = 'IM-1010';
            app.StopButton = uibutton(app.RecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'STOP';
            app.BaseFolderEditFieldLabel = uilabel(app.RecordingPanel);
            app.BaseFolderEditFieldLabel.Text = 'Base Folder';
            app.BaseFolderEditField = uieditfield(app.RecordingPanel, 'text');
            app.BaseFolderEditField.Value = 'E:\imaging';
            app.nmLEDSwitchLabel = uilabel(app.RecordingPanel);
            app.nmLEDSwitchLabel.Text = '405nm LED';
            app.switch_405 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_405.Value = 'On';
            app.nmLEDLabel_2 = uilabel(app.RecordingPanel);
            app.nmLEDLabel_2.Text = '470nm LED';
            app.switch_470 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_470.Value = 'On';
            app.nmLEDLabel = uilabel(app.RecordingPanel);
            app.nmLEDLabel.Text = '565nm LED';
            app.switch_565 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_565.Value = 'On';
            app.MesoscopeControlLabel = uilabel(app.UIFigure);
            app.MesoscopeControlLabel.Text = 'Mesoscope Control ';
            app.queSize = uilabel(app.UIFigure);
            app.queSize.Text = '';
            app.frameDrop = uilabel(app.UIFigure);
            app.frameDrop.Text = '';
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.BrowseButton = uibutton(app.ArduinoPanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.UploadtoArduinoButton = uibutton(app.ArduinoPanel, 'push');
            app.UploadtoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoArduinoButtonPushed, true);
            app.UploadtoArduinoButton.Text = 'Upload to Arduino';
            app.ReleaseCOMPortsButton = uibutton(app.ArduinoPanel, 'push');
            app.ReleaseCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseCOMPortsButtonPushed, true);
            app.ReleaseCOMPortsButton.Text = 'Release COM Ports';
            app.COMPortDropDownLabel = uilabel(app.ArduinoPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel.Text = 'Arduino Folder';
            app.ArduinoFolderEditField = uieditfield(app.ArduinoPanel, 'text');
            app.ArduinoFolderEditField.Value = 'Select your folder';
            app.ArduinoFileDropDownLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFileDropDownLabel.Text = 'Arduino File';
            app.ArduinoFileDropDown = uidropdown(app.ArduinoPanel);
            app.WaitingLabel = uilabel(app.ArduinoPanel);
            app.WaitingLabel.Text = 'Waiting...';
            app.PreviewSwitchLabel = uilabel(app.UIFigure);
            app.PreviewSwitchLabel.Text = 'Preview';
            app.PreviewSwitch = uiswitch(app.UIFigure, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mesoscope_control_RCS_Batch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end