classdef test_externalFigurePanels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end


    properties (Access = private)
        FigureHandle=nan(1,4);
        ImageAxes=nan(1,4);
        HistogramAxes=nan(1,4);
        Webcam;
        Timer;
    end

    methods (Access = private)
        % Update image and histogram function

        function createFigure(app,figNumber)

            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Define figure dimensions
            figWidth = 0.20 * screenWidth;
            figHeight = 0.80 * screenHeight;

            % Positions for the three figures
            pos(1,:) = [0, 0.10 * screenHeight, figWidth, figHeight]; % Left
            pos(2,:) = [0.20 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Center
            pos(3,:) = [0.40 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right
            pos(3,:) = [0.65 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right
            
            figureTitle={"Isosbestic Channel","Green Channel","Red Channel","Preview"}
            
            % Create a new figure window
            app.FigureHandle(figNumber) = figure('Name', figureTitle{figNumber},'Position', pos(figNumber,:));

            % Create two axes in the new figure with custom positions
            app.ImageAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.3, 0.8, 0.65]);
            app.HistogramAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.05, 0.8, 0.2]);
   
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Initialize webcam
            %app.Webcam = webcam;
            while true
                %img=snapshot(app.Webcam);
                img=randn(640,480);
                % Display the image
                % Ensure the figure is created
                figNumber=2;
                if isnan(app.FigureHandle(figNumber)) || ~isgraphics(app.FigureHandle(figNumber))
                    createFigure(app,figNumber);
                end
                imshow(img, 'Parent', app.ImageAxes{figNumber});
                %title(app.ImageAxes, 'Live Webcam Image');

                % Compute histogram
                [n, edges] = histcounts(img(:), 0:256); % Use 0:256 to include all intensity levels

                % Compute bin centers from edges
                binCenters = edges(1:end-1) + diff(edges) / 2;

                % Plot histogram
                bar(app.HistogramAxes{figNumber}, binCenters, n, 'FaceColor', 'b', 'EdgeColor', 'b');
                title(app.HistogramAxes{figNumber}, 'Histogram');
                xlabel(app.HistogramAxes{figNumber}, 'Pixel Intensity');
                ylabel(app.HistogramAxes{figNumber}, 'Count');
                pause(0.1)

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_externalFigurePanels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end