classdef mesoscope_control_crop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetFOVButton                  matlab.ui.control.Button
        CropFOVButton                   matlab.ui.control.Button
        DisplayPanel                    matlab.ui.container.Panel
        HistoryFramesEditField          matlab.ui.control.NumericEditField
        HistoryFramesEditFieldLabel     matlab.ui.control.Label
        SmoothingWindowEditField        matlab.ui.control.NumericEditField
        SmoothingWindowEditFieldLabel   matlab.ui.control.Label
        CameraQueryPausemsEditField     matlab.ui.control.NumericEditField
        CameraQueryPausemsEditFieldLabel  matlab.ui.control.Label
        ColorLimitMaxEditField          matlab.ui.control.NumericEditField
        ColorLimitMaxEditFieldLabel     matlab.ui.control.Label
        ColorLimitMinEditField          matlab.ui.control.NumericEditField
        ColorLimitMinEditFieldLabel     matlab.ui.control.Label
        UpdateDisplayFrameEditField     matlab.ui.control.NumericEditField
        UpdateDisplayFrameEditFieldLabel  matlab.ui.control.Label
        PreviewSwitch                   matlab.ui.control.Switch
        PreviewSwitchLabel              matlab.ui.control.Label
        ArduinoPanel                    matlab.ui.container.Panel
        LEDBuffermsEditField            matlab.ui.control.NumericEditField
        LEDBuffermsEditFieldLabel       matlab.ui.control.Label
        LEDonmsEditField                matlab.ui.control.NumericEditField
        LEDonmsEditFieldLabel           matlab.ui.control.Label
        WaitingLabel                    matlab.ui.control.Label
        ArduinoFileDropDown             matlab.ui.control.DropDown
        ArduinoFileDropDownLabel        matlab.ui.control.Label
        ArduinoFolderEditField          matlab.ui.control.EditField
        ArduinoFolderEditFieldLabel     matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        ReleaseCOMPortsButton           matlab.ui.control.Button
        UploadtoArduinoButton           matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        frameDrop                       matlab.ui.control.Label
        queSize                         matlab.ui.control.Label
        MesoscopeControlLabel           matlab.ui.control.Label
        RecordingPanel                  matlab.ui.container.Panel
        BatchWritingframeEditField      matlab.ui.control.NumericEditField
        BatchWritingframeEditFieldLabel  matlab.ui.control.Label
        switch_565                      matlab.ui.control.Switch
        nmLEDLabel                      matlab.ui.control.Label
        switch_470                      matlab.ui.control.Switch
        nmLEDLabel_2                    matlab.ui.control.Label
        switch_405                      matlab.ui.control.Switch
        nmLEDSwitchLabel                matlab.ui.control.Label
        BaseFolderEditField             matlab.ui.control.EditField
        BaseFolderEditFieldLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        CameraSettingsPanel             matlab.ui.container.Panel
        GainKnob                        matlab.ui.control.Knob
        GainKnobLabel                   matlab.ui.control.Label
        BufferSizeframesEditField       matlab.ui.control.NumericEditField
        BufferSizeframesEditFieldLabel  matlab.ui.control.Label
        BinSizepixelDropDown            matlab.ui.control.DropDown
        BinSizepixelDropDownLabel       matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        frameCounter        % Frame counter
        isRecording = false; % Flag to control the loop
        tlCamera; % camera object
        isPreview = false; %flag to control Preview functionality
        actualFrameNumber; %define number of frames


        selpath = '' % Description
        serialPort=[];
        StopFlag = false;
        %avrdude_path = "/Users/ali/Library/Arduino15/packages/arduino/tools/dfu-util/0.11.0-arduino5/dfu-util";
        avrdude_path = '"C:\GitHub\head-fixed-behavior\Tools\AVRDUDE\bin\avrdude"';
        avrdude_config = '"C:\GitHub\head-fixed-behavior\Tools\AVRDUDE\etc\avrdude.conf"';
        trialFinished  = false;
        running = true;
        serialNumbers=[];
        tlCameraSDK=[];
        logFileID=[];

        % Define custom colormaps
        bfpColormap = [linspace(0, 0, 255)', linspace(0, 0, 255)', linspace(0, 1, 255)'];  % Custom blue
        gfpColormap = [linspace(0, 0, 255)', linspace(0, 1, 255)', linspace(0, 0, 255)'];  % Custom green
        rfpColormap = [linspace(0, 1, 255)', linspace(0, 0, 255)', linspace(0, 0, 255)'];  % Custom red

        valueHistory=500;   % How many frames to use for dF/F calculation

        FigureHandle=nan(1,4);
        ImageAxes;
        HistogramAxes;
        dF;

        graphHandle=nan;
        trace_axes;

        cropRect = [];
        smoothing_window=1;
    end

    methods (Access = private)


        % Helper function to write a single frame


        function createFigure(app,figNumber)

            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Define figure dimensions
            figWidth = 0.20 * screenWidth;
            figHeight = 0.80 * screenHeight;

            % Positions for the three figures
            pos(1,:) = [0, 0.10 * screenHeight, figWidth, figHeight]; % Left
            pos(2,:) = [0.20 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Center
            pos(3,:) = [0.40 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right
            pos(4,:) = [0.65 * screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Right

            figureTitle={"Isosbestic Channel","Green Channel","Red Channel","Preview"}

            % Create a new figure window
            app.FigureHandle(figNumber) = figure('Name', figureTitle{figNumber},'Position', pos(figNumber,:));

            % Create two axes in the new figure with custom positions
            app.ImageAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.5, 0.8, 0.45]);
            app.HistogramAxes{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.2, 0.8, 0.18]);
            if figNumber==4
                app.dF{figNumber} = axes('Parent', app.FigureHandle(figNumber), 'Position', [0.1, 0.05, 0.8, 0.18]);
            end
        end

        function createGraph(app)
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Define figure dimensions
            figWidth = 0.4 * screenWidth;
            figHeight = 0.80 * screenHeight;

            % Positions for the three figures
            pos = [0.60* screenWidth, 0.10 * screenHeight, figWidth, figHeight]; % Left


            % Create a new figure window
            app.graphHandle= figure('Name', "Raw Traces",'Position', pos);

            % Create three axes in the new figure with custom positions
            app.trace_axes{1} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.6, 0.8, 0.25]);
            app.trace_axes{2} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.35, 0.8, 0.25]);
            app.trace_axes{3} = axes('Parent', app.graphHandle, 'Position', [0.1, 0.1, 0.8, 0.25]);


        end

        function writeSeriaalData(app,serialPort, logFileID)
            arduinoData = readline(serialPort); % Read available data
            input_string = strtrim(arduinoData); % Strip leading/trailing spaces and newlines
            split_string = strsplit(input_string, ',');
            fprintf(logFileID, '%s,', strtrim(split_string{1}));
            fprintf(logFileID, '%s,', strtrim(split_string{2}));
            fprintf(logFileID, '%s\n', strtrim(split_string{3}));
        end

        function handleROIClick(app, roi, evt)
            if strcmp(evt.SelectionType, 'double')  % Double-click confirms the crop
                app.cropRect = roi.Position;  % [x y width height]
                if all(app.cropRect(3:4) > 0)
                    save('cropRect.mat', 'app.cropRect');
                    disp(['Crop region saved: [', num2str(app.cropRect), ']']);
                else
                    warning('Invalid crop region. Not saved.');
                end
                delete(roi);  % Clean up ROI after confirmation
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("C:\GitHub\mesoscope"))
            %% Load the Camera
            try
                ThorFolder = 'C:\Thorlabs\Scientific_Camera_Interfaces_Windows-2.1\Scientific Camera Interfaces\MATLAB';
                addpath(genpath(ThorFolder));
                cd(ThorFolder);
                NET.addAssembly([ThorFolder, '\Thorlabs.TSI.TLCamera.dll']);
                app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;
                app.serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;

                app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
                app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
                exposureChange = app.ExposureTimemsEditField.Value *1000;
                app.tlCamera.ExposureTime_us = exposureChange;
                gainValue = app.GainKnob.Value; % Read the knob value
                mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
                app.tlCamera.Gain = mappedGain; % Set the gain
                app.tlCamera.Arm;
                app.tlCamera.IssueSoftwareTrigger;
                disp('Camera initialized.');

            catch ME
                disp(['Error during startup: ', ME.message]);
            end

            %% Populate the Arduino file and folders
            X=serialportlist("available");
            ttyPorts = X(contains(X, 'COM'));
            app.selpath='C:\GitHub\mesoscope\Arduino Codes';
            cd('C:\GitHub\mesoscope')
            app.COMPortDropDown.Items=ttyPorts;
            file=dir([app.selpath,filesep,'*.hex'])
            for i=1:length(file)
                LOC{i}=file(i).name;
            end
            app.ArduinoFileDropDown.Items = LOC;
            for i=1:length(file)
                if (strcmp(LOC{i},'cameraControl.ino.hex')==1)
                    app.ArduinoFileDropDown.Value = app.ArduinoFileDropDown.Items{i};
                end
            end
            app.ArduinoFolderEditField.Value = app.selpath;

            %profile on
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)

            if strcmp(app.PreviewSwitch.Value, 'On')
                disp('Preview is enabled.');
                app.isPreview = true;
                figNumber=4;
                if isnan(app.FigureHandle(figNumber)) || ~isgraphics(app.FigureHandle(figNumber))
                    createFigure(app,figNumber);
                    frameValues=[];count=0;
                end
                while app.isPreview
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    if (~isempty(imageFrame) && isvalid(app.ImageAxes{figNumber}))
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);

                        % Apply cropping if defined
                        if ~isempty(app.cropRect)
                            imageData2D = imcrop(imageData2D, app.cropRect);
                        end

                        meanPixelValue = mean(imageData2D(:));
                        colorLimits = [app.ColorLimitMinEditField.Value app.ColorLimitMaxEditField.Value];
                        imshow(imageData2D, 'Parent', app.ImageAxes{figNumber});
                        set(app.ImageAxes{figNumber}, 'CLim', colorLimits);
                        histogram(app.HistogramAxes{figNumber},imageData2D(:),0:20:5000);
                        xlim(app.HistogramAxes{figNumber},[0 5000])
                        title(app.HistogramAxes{figNumber}, 'Histogram');
                        xlabel(app.HistogramAxes{figNumber}, 'Pixel Intensity');
                        ylabel(app.HistogramAxes{figNumber}, 'Count');
                        delete(imageFrame);
                        count=count+1;
                        frameValues= [frameValues, meanPixelValue];  % Append mean value
                        if numel(frameValues) > app.valueHistory
                            frameValues = frameValues(end-app.valueHistory+1:end);  % Keep last 100 frames
                        end
                        dF_F = medfilt1(frameValues,app.smoothing_window);
                        plot(app.dF{figNumber},1:numel(frameValues), dF_F)
                        drawnow;
                    end
                end
            else
                disp('Preview is disabled.');
                app.isPreview = false;
                % Add code to stop the preview
            end

        end

        % Button pushed function: UploadtoArduinoButton
        function UploadtoArduinoButtonPushed(app, event)

            app.isPreview = false;
            app.UploadtoArduinoButton.Visible = "off";
            app.PreviewSwitch.Enable="off";
            app.COMPortDropDown.Enable="off";
            app.ArduinoFolderEditField.Enable="off";
            app.ArduinoFileDropDown.Enable="off";

            selected_port = app.COMPortDropDown.Value;
            hex_file_path = [app.ArduinoFolderEditField.Value,filesep,app.ArduinoFileDropDown.Value];

            command = strcat(...
                app.avrdude_path, ...
                ' -C', app.avrdude_config, ...
                ' -v', ...
                ' -patmega328p', ...
                ' -carduino', ...
                ' -P', selected_port, ...
                ' -b115200', ...
                ' -D', ...
                ' "-Uflash:w:', hex_file_path, ':i"');


            [status,cmdout] = system(command);
            if contains(cmdout, 'avrdude done.') && status==0
                app.WaitingLabel.Text='Successfully Uploaded.'
                app.WaitingLabel.FontColor='b';
            else
                app.WaitingLabel.Text='Upload Failed. Try Again!'
                app.WaitingLabel.FontColor='r';
            end
            delete(instrfindall)
            pause(2)
            app.RecordButton.Visible = "on";
            app.CameraSettingsPanel.Enable = 'on';
            app.LEDBuffermsEditField.Enable = "off";
            app.LEDonmsEditField.Enable = "off";

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

            %% Set camera Parameters
            app.tlCamera.Disarm; % Disarm the camera so we can update it's parameters such as Bin Size.
            BinSize = str2num(app.BinSizepixelDropDown.Value);
            app.tlCamera.ROIAndBin.BinX = BinSize;
            app.tlCamera.ROIAndBin.BinY = BinSize;
            app.tlCamera.MaximumNumberOfFramesToQueue = app.BufferSizeframesEditField.Value;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.ExposureTime_us = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.Arm;
            gainValue = app.GainKnob.Value; % Read the knob value
            mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
            app.tlCamera.Gain = mappedGain; % Set the gain


            %% Update display enable/disable
            app.isRecording = true;
            app.isPreview = false;
            app.RecordButton.Enable = 'off';
            app.CameraSettingsPanel.Enable = "off";
            app.BaseFolderEditField.Enable="off";
            app.ExperimenterEditField.Enable = "off";
            app.SubjectEditField.Enable = "off";
            app.UpdateDisplayFrameEditField.Enable= "off";
            app.CameraQueryPausemsEditField.Enable="off";
            app.CropFOVButton.Enable = "off";
            app.ResetFOVButton.Enable = "off";

            %% Pre-allocate variables

            colorMAP{1}=app.bfpColormap;colorMAP{2}=app.gfpColormap;colorMAP{3}=app.rfpColormap;
            title_trace={"405nm","470nm","565nm"};
            for i = 1:3, framePixelValues{i} = nan(1, app.valueHistory); end
            folderName{1}='405';folderName{2}='470';folderName{3}='565';
            capturedFrames = zeros(1,3);
            COLORS_trace=[0 0 0;0 1 0;1 0 0];

            baseDir = [app.BaseFolderEditField.Value filesep app.ExperimenterEditField.Value]; % Change this to your desired path
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            Subject_ID = app.SubjectEditField.Value;
            baseFolder = fullfile(baseDir, [Subject_ID '_' timestamp]);

            for i=1:3,mkdir([baseFolder filesep folderName{i}]);end

            cameraQueryPauseValue = app.CameraQueryPausemsEditField.Value/1000;

            %% Start logging Camera Details
            camera_log_name=[baseFolder filesep Subject_ID  '_' timestamp '_camera_log.txt'];
            selected_port = app.COMPortDropDown.Value;
            mode = (strcmp(app.switch_565.Value, 'On') * 4) + ...
                (strcmp(app.switch_470.Value, 'On') * 2) + ...
                (strcmp(app.switch_405.Value, 'On') * 1);
            %% Flipping mode 3,4 so that mode 1:3 mean LEDs1:3 on
            if mode==4
                mode=3;
            elseif mode==3
                mode=4;
            end

            %% End of mode Flipping...

            % Check if a parallel pool is already open
            if isempty(gcp('nocreate'))
                % No parallel pool open, so create a new one with 8 workers
                parpool('local', 4);
            else
                disp('A parallel pool is already open.');
            end

            LEDonDuration =app.LEDonmsEditField.Value*1000;
            LEDonBuffer = app.LEDBuffermsEditField.Value*1000;
            camera_log_task = parfeval(backgroundPool, @updateCameraLogFromArduino, 0,selected_port, camera_log_name, mode,LEDonDuration,LEDonBuffer);

            fileID = fopen([baseFolder filesep Subject_ID  '_' timestamp '_config.txt'], 'a'); % Open file for writing
            fprintf(fileID, 'Start time: %s\n', timestamp);
            fprintf(fileID, 'Experimenter: %s\n', app.ExperimenterEditField.Value);
            fprintf(fileID, 'Subject ID: %s\n', app.SubjectEditField.Value);
            fprintf(fileID, 'Camera Model: %s\n', app.tlCamera.Model);
            fprintf(fileID, 'Frame Rate Control Value fps: %d\n', app.tlCamera.FrameRateControlValue_fps);
            fprintf(fileID, 'Average fps: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Bin Size: %d\n', BinSize);
            fprintf(fileID, 'Exposure Time (Requested): %d  ms\n', app.ExposureTimemsEditField.Value);
            fprintf(fileID, 'Exposure Time (Actual): %d  s\n', app.tlCamera.ExposureTime_us);
            fprintf(fileID, 'Frame Time (Actual): %d  us\n', app.tlCamera.FrameTime_us);
            fprintf(fileID, 'Sensor Readout Time (Actual): %d  ns\n', app.tlCamera.SensorReadoutTime_ns);
            fprintf(fileID, 'Sensor Pixel Width: %d  um\n', app.tlCamera.SensorPixelWidth_um);
            fprintf(fileID, 'Bit Depth: %d\n', app.tlCamera.BitDepth);
            fprintf(fileID, 'Black Level: %d\n', app.tlCamera.BlackLevel);
            fprintf(fileID, 'Frames Per Second: %d\n', app.tlCamera.FramesPerSecond);
            fprintf(fileID, 'Camera Gain: %d\n', app.tlCamera.Gain);
            fprintf(fileID, 'Knob Gain: %d\n', app.GainKnob.Value);
            fprintf(fileID, 'OperationMode: %s\n', app.tlCamera.OperationMode);
            fprintf(fileID, 'HotPixel Correction Threshold: %d\n', app.tlCamera.HotPixelCorrectionThreshold);
            fprintf(fileID, '405 LED: %s\n', app.switch_405.Value);
            fprintf(fileID, '470 LED: %s\n', app.switch_470.Value);
            fprintf(fileID, '565 LED: %s\n', app.switch_565.Value);
            fprintf(fileID, 'Image Height: %d\n', app.tlCamera.ImageHeight_pixels);
            fprintf(fileID, 'Image Width: %d\n', app.tlCamera.ImageWidth_pixels);
            if ~isempty(app.cropRect)
                fprintf(fileID, 'Crop X: %d\n', app.cropRect(1));
                fprintf(fileID, 'Crop Y: %d\n', app.cropRect(2));
                fprintf(fileID, 'Crop Width: %d\n', app.cropRect(3));
                fprintf(fileID, 'Crop Height: %d\n', app.cropRect(4));
            end


            %% Start Imaging
            pause(10);
            app.StopButton.Visible = 'on';
            app.tlCamera.IssueSoftwareTrigger;
            disp('Starting continuous image acquisition.');
            frameCount = 0;

            createFigure(app,1);createFigure(app,2);createFigure(app,3);
            createGraph(app);

            displayUpdateRate=app.UpdateDisplayFrameEditField.Value;
            imageHandle{1}=[];imageHandle{2}=[];imageHandle{3}=[];
            HistogramBarHandle{1}=[];HistogramBarHandle{2}=[];HistogramBarHandle{3}=[];
            TracePlotHandle{1}=[];TracePlotHandle{2}=[];TracePlotHandle{3}=[];

            imageHeight = app.tlCamera.ImageHeight_pixels;
            imageWidth = app.tlCamera.ImageWidth_pixels;

            batchSize = app.BatchWritingframeEditField.Value;
            futures = parallel.FevalFuture.empty;
            maxFutures = 10; % Limit the number of concurrent futures
            frameBatch = cell(batchSize, 1);
            for j=1:3
                for i = 1:batchSize
                    frameBatch{i,j} = uint16(zeros(imageWidth, imageHeight));
                end
            end
            currentBatchIndex=zeros(1,3);
            addressBatch=cell(batchSize,3);

            while app.isRecording
                pause(cameraQueryPauseValue);
                % Check if image buffer has been filled
                NumberOfQueuedFrames = app.tlCamera.NumberOfQueuedFrames;
                if (NumberOfQueuedFrames > 0)

                    imageFrame  = app.tlCamera.GetPendingFrameOrNull;
                    actualFrameNumber = imageFrame.FrameNumber;

                    if ~isempty(imageFrame)


                        %% Determine the frame Color
                        switch mode
                            case {1, 2, 3}
                                frameColor = mode;
                            case 4
                                frameColor = 1 + mod(actualFrameNumber, 2);
                            case 5
                                frameColor = 1 + 2 * mod(actualFrameNumber, 2);
                            case 6
                                frameColor = 2 + mod(actualFrameNumber, 2);
                            case 7
                                frameColor = 1 + mod(actualFrameNumber, 3);
                        end

                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        delete(imageFrame);
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);


                        subFolderName= [baseFolder,'\',folderName{frameColor}, '\',num2str(ceil(actualFrameNumber/10000))];
                        fileName = sprintf('image_%08d.bin', actualFrameNumber);
                        filePath =  [subFolderName,'\',fileName];  % Create a new folder every 10,000 frames

                        if ~exist(subFolderName, 'dir') % If the folder does not exist, create it
                            mkdir(subFolderName);
                        end

                        currentBatchIndex(frameColor)=currentBatchIndex(frameColor)+1;
                        addressBatch{currentBatchIndex(frameColor),frameColor}=filePath;
                        frameBatch{currentBatchIndex(frameColor),frameColor}=imageData2D;
                        if currentBatchIndex(frameColor) >= batchSize
                            currentBatchIndex(frameColor)=0;
                            %writeBatchFrames_binary(frameBatch(:,frameColor), addressBatch(:,frameColor));
                            futures(end+1) = parfeval(@writeBatchFrames_binary, 0, frameBatch(:,frameColor), addressBatch(:,frameColor));
                            if numel(futures) > maxFutures
                                % Wait for some futures to complete or cancel them
                                for i = numel(futures):-1:1
                                    if strcmp(futures(i).State, 'finished')
                                        % Delete the completed future
                                        delete(futures(i));
                                        futures(i) = [];
                                    end
                                end
                            end
                        end

                        frameCount = frameCount + 1;
                        capturedFrames(frameColor) = capturedFrames(frameColor)+1;


                        %% Update the dF/F graph

                        % Apply cropping if defined
                        if ~isempty(app.cropRect)
                            imageData2D = imcrop(imageData2D, app.cropRect);
                            imageData = double(imageData2D(:));
                        end

                        % Calculate the mean pixel value for the current frame
                        meanPixelValue = mean(imageData);
                        % Update the mean pixel values buffer for UIAxes1 (limit to 100 frames)
                        framePixelValues{frameColor} = [framePixelValues{frameColor}, meanPixelValue];  % Append mean value
                        if numel(framePixelValues{frameColor}) > app.valueHistory
                            framePixelValues{frameColor} = framePixelValues{frameColor}(end-app.valueHistory+1:end);  % Keep last 100 frames
                        end
                        dF_F = 100*(framePixelValues{frameColor}-mean(framePixelValues{frameColor}))./(framePixelValues{frameColor});
                        dF_F = medfilt1(framePixelValues{frameColor},app.smoothing_window);

                        %

                        %% Display the image
                        %delete(imageFrame);
                        if (mod(frameCount, displayUpdateRate) == 0)

                            app.queSize.Text= ['Queued Frames: ', num2str(NumberOfQueuedFrames)];
                            app.frameDrop.Text= ['Dropped Frames: ', num2str(actualFrameNumber-frameCount)];

                            % Get color limits from the UI
                            minVal = app.ColorLimitMinEditField.Value;
                            maxVal = app.ColorLimitMaxEditField.Value;

                            % Validate the color limits
                            if minVal > maxVal
                                % warndlg('Minimum color limit cannot be greater than maximum color limit.', ...
                                %     'Invalid Color Limits');
                                maxVal = minVal+1;
                                app.ColorLimitMaxEditField.Value = maxVal;
                            end

                            % Set color limits to match the 12-bit range [0, 4095]
                            colorLimits = [minVal, maxVal];

                            % Initialize imageHandle once, then only update its data. It helps the speed
                            if isempty(imageHandle{frameColor}) % capturedFrames(frameColor)==1
                                imageHandle{frameColor} = imshow(imageData2D, 'Parent', app.ImageAxes{frameColor});
                                colormap(app.ImageAxes{frameColor}, colorMAP{frameColor});  % Apply custom blue colormap
                                axes(app.ImageAxes{frameColor});
                                axis(app.ImageAxes{frameColor}, 'image'); % Keep aspect ratio
                            else
                                set(imageHandle{frameColor}, 'CData', imageData2D);  % Update image data
                                set(app.ImageAxes{frameColor}, 'CLim', colorLimits);
                            end

                            title(app.ImageAxes{frameColor}, sprintf('Frame %d ', actualFrameNumber));

                            % Initialize the trace plot
                            if isempty(TracePlotHandle{frameColor})
                                hPlot = plot(app.trace_axes{frameColor}, 1:numel(framePixelValues{frameColor}), dF_F, "Color", COLORS_trace(frameColor, :), "LineWidth", 2);
                                TracePlotHandle{frameColor} = hPlot;  % Store handle for future use

                                % Set additional properties once
                                title(app.trace_axes{frameColor}, title_trace{frameColor}, "FontSize", 16);
                                xlabel(app.trace_axes{frameColor}, 'Frame Number');
                                ylabel(app.trace_axes{frameColor}, 'dF/F(%)');
                                set(app.trace_axes{frameColor}, "XTICK", []);
                            else
                                % Update the existing trace plot
                                set(TracePlotHandle{frameColor}, 'XData', 1:numel(framePixelValues{frameColor}), 'YData', dF_F);
                            end


                            %% Plot the histogram
                            % Define bin edges for the histogram
                            binEdges = 0:20:5000;
                            % Pre-compute histogram counts
                            [counts, edges] = histcounts(imageData, binEdges);
                            % Initial creation of the bar plot
                            if isempty(HistogramBarHandle{frameColor})
                                % Create the bar plot for the first time
                                HistogramBarHandle{frameColor} = bar(app.HistogramAxes{frameColor}, edges(1:end-1), counts,1);
                                xlim(app.HistogramAxes{frameColor},[0 5000])
                            else
                                % Update the existing bar plot
                                set(HistogramBarHandle{frameColor},'YData', counts);
                                %ylim(app.HistogramAxes{frameColor},[0 max(counts)*1.1]);

                            end
                            %drawnow;  % Update the figure window, every 10 frames
                        end
                    end
                end
            end


            % Write the remaining frames in the batch
            for frameColor=1:3
                dummy= currentBatchIndex(frameColor);
                if dummy>0
                    writeBatchFrames_binary(frameBatch(1:dummy,frameColor), addressBatch(1:dummy,frameColor));
                end
            end


            fprintf(fileID, 'Total Requested Frames: %d\n', frameCount);
            fprintf(fileID, 'Total Recorded Frames: %d\n', actualFrameNumber);
            fprintf(fileID, 'End time: %s\n', datestr(now, 'yyyy-mm-dd_HH-MM-SS'));
            fclose(fileID)

            disp('Shutting Down...')
            pause(10)
            % Function to close any open serial ports
            closeSerialPortFcn = @() evalc('try, fclose(instrfind), delete(instrfind), catch, end');
            % Broadcast the function to all workers
            parfevalOnAll(closeSerialPortFcn, 0);
            disp('All workers have been instructed to close and delete any open serial ports.');
            delete(instrfindall)


            % Function to check and shut down parallel pool when not busy
            pool = gcp('nocreate'); % Get the current parallel pool without creating a new one
            while (pool.Busy)
                disp('Parallel pool is busy. Waiting to shut it down...');
                pause(5); % Wait for 10 seconds before checking again
            end
            delete(pool);


            disp('It is now safe to close MATLAB');

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stopping recording...');
            app.isRecording = false;
            app.isPreview = false;
            app.StopButton.Enable = "off";
            % Disarm and release the camera
            app.tlCamera.Disarm;
            disp('Recording Stopped');
            %profile off
            %profile viewer
        end

        % Button pushed function: ReleaseCOMPortsButton
        function ReleaseCOMPortsButtonPushed(app, event)
            delete(instrfindall)

            X=serialportlist("available");
            if ismac
                ttyPorts = X(contains(X, 'tty.usb'));
            else
                ttyPorts = X(contains(X, 'COM'));
            end
            app.COMPortDropDown.Items=ttyPorts;
            app.WaitingLabel.Text = 'Waiting...';
            app.WaitingLabel.FontColor = 'm';
            app.UploadtoArduinoButton.Visible = "on";
            app.PreviewSwitch.Enable="on";
        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            exposureChange = app.ExposureTimemsEditField.Value *1000;
            app.tlCamera.ExposureTime_us = exposureChange;
            valueChange = sprintf('exposure changed to:',exposureChange);
            disp(valueChange);
        end

        % Value changed function: GainKnob
        function GainChange(app, event)
            if ~isempty(app.tlCamera) && isvalid(app.tlCamera)
                gainValue = app.GainKnob.Value; % Read the knob value
                mappedGain = gainValue / 100 * app.tlCamera.GainRange.Maximum; % Map to camera's gain range
                app.tlCamera.Gain = mappedGain; % Set the gain
                currentGain = sprintf('Gain = %.2f', mappedGain); % Format the gain as a string
                disp(currentGain); % Display the gain value
                disp(['Knob Value: ', num2str(app.GainKnob.Value)]);
            else
                warning('Camera not initialized or not valid.');
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Get the current parallel pool
            if app.tlCamera.IsArmed
                app.tlCamera.Disarm;
            end
            app.tlCamera.Dispose;
            delete(app.tlCamera);
            % Release the serial numbers
            delete(app.serialNumbers);
            % Release the TLCameraSDK.
            app.tlCameraSDK.Dispose;
            delete(app.tlCameraSDK);

            % Get the current parallel pool without creating a new one
            pool = gcp('nocreate');
            % Check if the pool exists
            if ~isempty(pool)
                % Wait until the pool is not busy
                while pool.Busy
                    disp('Parallel pool is busy. Waiting to shut it down...');
                    pause(5); % Wait for 5 seconds before checking again
                end
                % Delete the pool when it's not busy
                delete(pool);
                disp('Parallel pool shut down.');
            else
                disp('Parallel pool already shut down.');
            end


            disp('It is now safe to close MATLAB');
            delete(app);
            delete(app.UIFigure); % This will close the app's main figure window


        end

        % Button pushed function: CropFOVButton
        function CropFOVButtonPushed(app, event)
            disp('Crop button pressed. Stopping preview...');

            % Stop preview loop
            app.isPreview = false;
            pause(0.5);  % Allow preview loop to exit cleanly

            figNumber = 4;

            if isvalid(app.ImageAxes{figNumber})
                % Clear previous ROIs if any
                delete(findall(app.ImageAxes{figNumber}, 'Type', 'images.roi.Rectangle'));

                disp('Draw a rectangle to define the FOV (double-click to confirm)...');
                roi = drawrectangle(app.ImageAxes{figNumber}, 'Color', 'r');

                % Wait for user to double-click to finalize ROI
                addlistener(roi, 'ROIClicked', @(~, evt) handleROIClick(app, roi, evt));
            end
            app.isPreview = true;
            pause(0.5);  % Allow loop to break cleanly

        end

        % Button pushed function: ResetFOVButton
        function ResetFOVButtonPushed(app, event)
            app.isPreview = false;
            app.cropRect = [];
            pause(.5)
            app.isPreview = true;
        end

        % Value changed function: SmoothingWindowEditField
        function SmoothingWindowEditFieldValueChanged(app, event)
            app.smoothing_window = app.SmoothingWindowEditField.Value;
        end

        % Value changed function: HistoryFramesEditField
        function HistoryFramesEditFieldValueChanged(app, event)
            value = app.HistoryFramesEditField.Value;
            app.valueHistory = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CameraSettingsPanel = uipanel(app.UIFigure);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.ExposureTimemsEditField.Value = 23;
            app.BinSizepixelDropDownLabel = uilabel(app.CameraSettingsPanel);
            app.BinSizepixelDropDownLabel.Enable = 'off';
            app.BinSizepixelDropDownLabel.Text = 'BinSize (pixel)';
            app.BinSizepixelDropDown = uidropdown(app.CameraSettingsPanel);
            app.BinSizepixelDropDown.Items = {'1', '2', '4', '8'};
            app.BinSizepixelDropDown.Enable = 'off';
            app.BinSizepixelDropDown.Value = '4';
            app.BufferSizeframesEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.BufferSizeframesEditFieldLabel.Text = 'Buffer Size (frames)';
            app.BufferSizeframesEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.BufferSizeframesEditField.Value = 500;
            app.GainKnobLabel = uilabel(app.CameraSettingsPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.CameraSettingsPanel, 'continuous');
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainChange, true);
            app.GainKnob.Value = 25;
            app.RecordingPanel = uipanel(app.UIFigure);
            app.RecordingPanel.Title = 'Recording';
            app.RecordButton = uibutton(app.RecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Visible = 'off';
            app.RecordButton.Text = 'Record';
            app.ExperimenterEditFieldLabel = uilabel(app.RecordingPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.RecordingPanel, 'text');
            app.ExperimenterEditField.Value = 'Rob';
            app.SubjectEditFieldLabel = uilabel(app.RecordingPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordingPanel, 'text');
            app.SubjectEditField.Value = 'IM-1010';
            app.StopButton = uibutton(app.RecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'STOP';
            app.BaseFolderEditFieldLabel = uilabel(app.RecordingPanel);
            app.BaseFolderEditFieldLabel.Text = 'Base Folder';
            app.BaseFolderEditField = uieditfield(app.RecordingPanel, 'text');
            app.BaseFolderEditField.Value = 'E:\imaging';
            app.nmLEDSwitchLabel = uilabel(app.RecordingPanel);
            app.nmLEDSwitchLabel.Text = '405nm LED';
            app.switch_405 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_405.Value = 'On';
            app.nmLEDLabel_2 = uilabel(app.RecordingPanel);
            app.nmLEDLabel_2.Text = '470nm LED';
            app.switch_470 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_470.Value = 'On';
            app.nmLEDLabel = uilabel(app.RecordingPanel);
            app.nmLEDLabel.Text = '565nm LED';
            app.switch_565 = uiswitch(app.RecordingPanel, 'slider');
            app.switch_565.Value = 'On';
            app.BatchWritingframeEditFieldLabel = uilabel(app.RecordingPanel);
            app.BatchWritingframeEditFieldLabel.Text = 'Batch Writing (frame)';
            app.BatchWritingframeEditField = uieditfield(app.RecordingPanel, 'numeric');
            app.BatchWritingframeEditField.Value = 50;
            app.MesoscopeControlLabel = uilabel(app.UIFigure);
            app.MesoscopeControlLabel.Text = 'Mesoscope Control ';
            app.queSize = uilabel(app.UIFigure);
            app.queSize.Text = '';
            app.frameDrop = uilabel(app.UIFigure);
            app.frameDrop.Text = '';
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.BrowseButton = uibutton(app.ArduinoPanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.UploadtoArduinoButton = uibutton(app.ArduinoPanel, 'push');
            app.UploadtoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoArduinoButtonPushed, true);
            app.UploadtoArduinoButton.Text = 'Upload to Arduino';
            app.ReleaseCOMPortsButton = uibutton(app.ArduinoPanel, 'push');
            app.ReleaseCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseCOMPortsButtonPushed, true);
            app.ReleaseCOMPortsButton.Text = 'Release COM Ports';
            app.COMPortDropDownLabel = uilabel(app.ArduinoPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFolderEditFieldLabel.Text = 'Arduino Folder';
            app.ArduinoFolderEditField = uieditfield(app.ArduinoPanel, 'text');
            app.ArduinoFolderEditField.Value = 'Select your folder';
            app.ArduinoFileDropDownLabel = uilabel(app.ArduinoPanel);
            app.ArduinoFileDropDownLabel.Text = 'Arduino File';
            app.ArduinoFileDropDown = uidropdown(app.ArduinoPanel);
            app.WaitingLabel = uilabel(app.ArduinoPanel);
            app.WaitingLabel.Text = 'Waiting...';
            app.LEDonmsEditFieldLabel = uilabel(app.ArduinoPanel);
            app.LEDonmsEditFieldLabel.Text = 'LED on  (ms)';
            app.LEDonmsEditField = uieditfield(app.ArduinoPanel, 'numeric');
            app.LEDonmsEditField.Value = 8;
            app.LEDBuffermsEditFieldLabel = uilabel(app.ArduinoPanel);
            app.LEDBuffermsEditFieldLabel.Text = 'LED Buffer (ms)';
            app.LEDBuffermsEditField = uieditfield(app.ArduinoPanel, 'numeric');
            app.LEDBuffermsEditField.Value = 5;
            app.PreviewSwitchLabel = uilabel(app.UIFigure);
            app.PreviewSwitchLabel.Text = 'Preview';
            app.PreviewSwitch = uiswitch(app.UIFigure, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.UpdateDisplayFrameEditFieldLabel = uilabel(app.DisplayPanel);
            app.UpdateDisplayFrameEditFieldLabel.Text = 'Update Display (Frame)';
            app.UpdateDisplayFrameEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.UpdateDisplayFrameEditField.Value = 20;
            app.ColorLimitMinEditFieldLabel = uilabel(app.DisplayPanel);
            app.ColorLimitMinEditFieldLabel.Text = 'Color Limit Min';
            app.ColorLimitMinEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.ColorLimitMaxEditFieldLabel = uilabel(app.DisplayPanel);
            app.ColorLimitMaxEditFieldLabel.Text = 'Color Limit Max';
            app.ColorLimitMaxEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.ColorLimitMaxEditField.Value = 4095;
            app.CameraQueryPausemsEditFieldLabel = uilabel(app.DisplayPanel);
            app.CameraQueryPausemsEditFieldLabel.Text = 'Camera Query Pause (ms)';
            app.CameraQueryPausemsEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.CameraQueryPausemsEditField.Value = 2;
            app.SmoothingWindowEditFieldLabel = uilabel(app.DisplayPanel);
            app.SmoothingWindowEditFieldLabel.Text = 'Smoothing Window';
            app.SmoothingWindowEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.SmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingWindowEditFieldValueChanged, true);
            app.SmoothingWindowEditField.Value = 5;
            app.HistoryFramesEditFieldLabel = uilabel(app.DisplayPanel);
            app.HistoryFramesEditFieldLabel.Text = 'History (Frames)';
            app.HistoryFramesEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.HistoryFramesEditField.ValueChangedFcn = createCallbackFcn(app, @HistoryFramesEditFieldValueChanged, true);
            app.HistoryFramesEditField.Value = 500;
            app.CropFOVButton = uibutton(app.UIFigure, 'push');
            app.CropFOVButton.ButtonPushedFcn = createCallbackFcn(app, @CropFOVButtonPushed, true);
            app.CropFOVButton.Text = 'Crop FOV';
            app.ResetFOVButton = uibutton(app.UIFigure, 'push');
            app.ResetFOVButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFOVButtonPushed, true);
            app.ResetFOVButton.Text = 'Reset FOV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mesoscope_control_crop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end